classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainApp                         matlab.ui.Figure
        Grid1                           matlab.ui.container.GridLayout
        Panel2                          matlab.ui.container.Panel
        Grid_21                         matlab.ui.container.GridLayout
        Tree_22                         matlab.ui.container.Tree
        Tree_21                         matlab.ui.container.Tree
        Label_23                        matlab.ui.control.Label
        Label_22                        matlab.ui.control.Label
        Label_21                        matlab.ui.control.Label
        Warning_21                      matlab.ui.control.Image
        Button_29                       matlab.ui.control.Button
        Button_26                       matlab.ui.control.StateButton
        Button_25                       matlab.ui.control.Button
        Button_24                       matlab.ui.control.Button
        Button_23                       matlab.ui.control.StateButton
        Button_22                       matlab.ui.control.StateButton
        Button_21                       matlab.ui.control.StateButton
        Panel1                          matlab.ui.container.Panel
        Grid_11                         matlab.ui.container.GridLayout
        Tree_11                         matlab.ui.container.Tree
        Button_15                       matlab.ui.control.Button
        Button_12                       matlab.ui.control.Button
        Button_11                       matlab.ui.control.Button
        Label_13                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Grid_12                         matlab.ui.container.GridLayout
        metaData_11                     matlab.ui.control.EditField
        FormatodoarquivoLabel           matlab.ui.control.Label
        metaData_12                     matlab.ui.control.EditField
        IntrumentoEditFieldLabel        matlab.ui.control.Label
        ThreadIDDropDownLabel           matlab.ui.control.Label
        metaData_13                     matlab.ui.control.DropDown
        metaData_14                     matlab.ui.control.EditField
        FaixadefrequnciaEditFieldLabel  matlab.ui.control.Label
        metaData_15                     matlab.ui.control.TextArea
        DescrioTextAreaLabel            matlab.ui.control.Label
        GridLayout8                     matlab.ui.container.GridLayout
        Button_35                       matlab.ui.control.Button
        Button_34                       matlab.ui.control.Button
        Button_33                       matlab.ui.control.Button
        Button_32                       matlab.ui.control.Button
        Button_31                       matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        Panel3_Info                     matlab.ui.container.Panel
        Info_Grid1                      matlab.ui.container.GridLayout
        Info_Panel2                     matlab.ui.container.Panel
        Info_Grid3                      matlab.ui.container.GridLayout
        LongitudeEditField_2Label       matlab.ui.control.Label
        Info_Latitude                   matlab.ui.control.NumericEditField
        Info_Longitude                  matlab.ui.control.NumericEditField
        LatitudeEditField_2Label        matlab.ui.control.Label
        Info_CheckBox                   matlab.ui.control.CheckBox
        Info_OCC                        matlab.ui.control.DropDown
        OcupaoDropDownLabel             matlab.ui.control.Label
        Info_Button                     matlab.ui.control.Button
        IntrumentoEditFieldLabel_2      matlab.ui.control.Label
        FluxodedadosLabel               matlab.ui.control.Label
        Info_Label2                     matlab.ui.control.Label
        Info_Label1                     matlab.ui.control.Label
        Info_MetaData1                  matlab.ui.control.EditField
        Info_MetaData2                  matlab.ui.control.EditField
        Info_Panel1                     matlab.ui.container.Panel
        Info_Grid2                      matlab.ui.container.GridLayout
        LongitudeEditFieldLabel         matlab.ui.control.Label
        Info_MetaData6                  matlab.ui.control.NumericEditField
        Info_MetaData5                  matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel          matlab.ui.control.Label
        Info_MetaData4                  matlab.ui.control.EditField
        LocalidadeLabel                 matlab.ui.control.Label
        Info_MetaData3                  matlab.ui.control.EditField
        UnidaderesponsvelLabel_2        matlab.ui.control.Label
        Panel3_Report                   matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        Panel3_TabGroup                 matlab.ui.container.TabGroup
        Report_Tab1                     matlab.ui.container.Tab
        Report_Grid1                    matlab.ui.container.GridLayout
        InspeoSistemaFiscalizaEditFieldLabel  matlab.ui.control.Label
        UnidaderesponsvelLabel          matlab.ui.control.Label
        Report_Value1                   matlab.ui.control.DropDown
        Report_Value2                   matlab.ui.control.NumericEditField
        Report_Label1                   matlab.ui.control.Label
        Report_Panel1                   matlab.ui.container.Panel
        Report_Grid2                    matlab.ui.container.GridLayout
        Report_CheckBox1                matlab.ui.control.CheckBox
        Report_Label2                   matlab.ui.control.Label
        Report_CheckBox2                matlab.ui.control.CheckBox
        Report_CheckBox3                matlab.ui.control.CheckBox
        Report_CheckBox4                matlab.ui.control.CheckBox
        Report_TextArea                 matlab.ui.control.TextArea
        Report_CheckBox5                matlab.ui.control.CheckBox
        Report_Tab2                     matlab.ui.container.Tab
        Report_Grid3                    matlab.ui.container.GridLayout
        Report_Panel2                   matlab.ui.container.Panel
        Report_Grid5                    matlab.ui.container.GridLayout
        TipoDropDownLabel               matlab.ui.control.Label
        TempodeintegraoLabel            matlab.ui.control.Label
        Report_Unit5                    matlab.ui.control.Label
        Report_Unit4                    matlab.ui.control.Label
        Report_Label6                   matlab.ui.control.Label
        Report_Label5                   matlab.ui.control.Label
        Report_Value3                   matlab.ui.control.DropDown
        Report_Value4                   matlab.ui.control.Spinner
        Report_Value7                   matlab.ui.control.Spinner
        AmostrasdescartadasLabel        matlab.ui.control.Label
        Report_Value8                   matlab.ui.control.Spinner
        AmostrasteisLabel               matlab.ui.control.Label
        Report_Value6                   matlab.ui.control.DropDown
        FunoestatsticaDropDownLabel     matlab.ui.control.Label
        Report_Value5                   matlab.ui.control.Spinner
        OffSetLabel_2                   matlab.ui.control.Label
        Report_Label4                   matlab.ui.control.Label
        Report_Label3                   matlab.ui.control.Label
        Report_Grid4                    matlab.ui.container.GridLayout
        Report_DatePicker1              matlab.ui.control.DatePicker
        Report_Spinner1                 matlab.ui.control.Spinner
        Report_Spinner2                 matlab.ui.control.Spinner
        Report_DatePicker2              matlab.ui.control.DatePicker
        Report_Spinner3                 matlab.ui.control.Spinner
        Report_Spinner4                 matlab.ui.control.Spinner
        Report_Tab3                     matlab.ui.container.Tab
        Report_Grid6                    matlab.ui.container.GridLayout
        Panel_6                         matlab.ui.container.Panel
        Report_Grid10                   matlab.ui.container.GridLayout
        Report_Unit16                   matlab.ui.control.Label
        Report_Unit15                   matlab.ui.control.Label
        Report_Unit14                   matlab.ui.control.Label
        Report_Unit13                   matlab.ui.control.Label
        Report_Unit12                   matlab.ui.control.Label
        Report_Unit11                   matlab.ui.control.Label
        Report_Unit10                   matlab.ui.control.Label
        ProeminnciamnimaemrelaoadjacnciaLabel  matlab.ui.control.Label
        LarguradebandamnimaLabel        matlab.ui.control.Label
        DistnciamnimaentrepicosLabel_2  matlab.ui.control.Label
        ProeminnciamnimaemrelaoadjacnciaLabel_2  matlab.ui.control.Label
        LarguradebandamnimaLabel_2      matlab.ui.control.Label
        DistnciamnimaentrepicosLabel    matlab.ui.control.Label
        OcupaomnimaLabel                matlab.ui.control.Label
        Report_Value9                   matlab.ui.control.DropDown
        Report_Value10                  matlab.ui.control.Spinner
        Report_Value11                  matlab.ui.control.Spinner
        Report_Value12                  matlab.ui.control.Spinner
        Report_CheckBox6                matlab.ui.control.CheckBox
        Report_Value13                  matlab.ui.control.Spinner
        FunoestatsticaDropDown_2Label   matlab.ui.control.Label
        Report_Value14                  matlab.ui.control.Spinner
        Report_Value15                  matlab.ui.control.Spinner
        Report_Value16                  matlab.ui.control.Spinner
        Report_CheckBox7                matlab.ui.control.CheckBox
        Report_Label8                   matlab.ui.control.Label
        Report_Label9                   matlab.ui.control.Label
        Report_Label7                   matlab.ui.control.Label
        Button_31r                      matlab.ui.control.Button
        Panel3_Spec                     matlab.ui.container.Panel
        Grid31                          matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        CheckBox_05                     matlab.ui.control.CheckBox
        Panel_14                        matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        Report_Value6_3                 matlab.ui.control.DropDown
        Report_Value8_2                 matlab.ui.control.Spinner
        ThresholdDropDownLabel          matlab.ui.control.Label
        OffSetLabel                     matlab.ui.control.Label
        FatordeintegraoLabel_2          matlab.ui.control.Label
        ValorLabel                      matlab.ui.control.Label
        Label_29                        matlab.ui.control.Label
        Report_Value5_3                 matlab.ui.control.Spinner
        Report_Value6_2                 matlab.ui.control.DropDown
        Value31_3                       matlab.ui.control.Spinner
        THR_Unit                        matlab.ui.control.Label
        THR_Value                       matlab.ui.control.Spinner
        FunoestatsticaDropDownLabel_3   matlab.ui.control.Label
        AmostrasteisLabel_2             matlab.ui.control.Label
        NveldeconfianaLabel             matlab.ui.control.Label
        THR_Type                        matlab.ui.control.DropDown
        CheckBox_04                     matlab.ui.control.CheckBox
        Panel_13                        matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        CheckBox_03                     matlab.ui.control.CheckBox
        CheckBox_02                     matlab.ui.control.CheckBox
        Label_26                        matlab.ui.control.Label
        RadioButtonGroup_02             matlab.ui.container.ButtonGroup
        Value_01                        matlab.ui.control.Spinner
        RadioButton_05                  matlab.ui.control.RadioButton
        FatordeintegraoLabel            matlab.ui.control.Label
        RadioButton_04                  matlab.ui.control.RadioButton
        RadioButton_03                  matlab.ui.control.RadioButton
        CheckBox_01                     matlab.ui.control.CheckBox
        RadioButtonGroup_01             matlab.ui.container.ButtonGroup
        RadioButton_02                  matlab.ui.control.RadioButton
        RadioButton_01                  matlab.ui.control.RadioButton
        Label_01                        matlab.ui.control.Label
        Tab_2                           matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        Label32                         matlab.ui.control.Label
        ButtonGroup31                   matlab.ui.container.ButtonGroup
        Button33                        matlab.ui.control.RadioButton
        Button32                        matlab.ui.control.RadioButton
        Button31                        matlab.ui.control.RadioButton
        Pk_Value1                       matlab.ui.control.Spinner
        PicosLabel                      matlab.ui.control.Label
        DistnciaLabel                   matlab.ui.control.Label
        Pk_Value2                       matlab.ui.control.Spinner
        Pk_Unit2                        matlab.ui.control.Label
        Pk_Value3                       matlab.ui.control.Spinner
        LarguradebandaLabel             matlab.ui.control.Label
        Pk_Unit3                        matlab.ui.control.Label
        Pk_Value4                       matlab.ui.control.Spinner
        ProeminnciaLabel                matlab.ui.control.Label
        Pk_Unit4                        matlab.ui.control.Label
        Pk_Value5                       matlab.ui.control.Spinner
        ThresholdLabel                  matlab.ui.control.Label
        Pk_Unit5                        matlab.ui.control.Label
        Pk_Label1                       matlab.ui.control.Label
        Pk_Value6                       matlab.ui.control.NumericEditField
        Pk_Unit6                        matlab.ui.control.Label
        Pk_Value7                       matlab.ui.control.NumericEditField
        Pk_Unit7                        matlab.ui.control.Label
        Pk_Label2                       matlab.ui.control.Label
        Pk_Value8                       matlab.ui.control.Spinner
        Pk_Value9                       matlab.ui.control.NumericEditField
        Pk_Unit8                        matlab.ui.control.Label
        Pk_Value10                      matlab.ui.control.NumericEditField
        Pk_Unit9                        matlab.ui.control.Label
        CheckBox34                      matlab.ui.control.CheckBox
        Tab_3                           matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        Label31_5                       matlab.ui.control.Label
        BarrademenuCheckBox             matlab.ui.control.CheckBox
        BarradeferramentasCheckBox      matlab.ui.control.CheckBox
        Slider31                        matlab.ui.control.Slider
        Label31_6                       matlab.ui.control.Label
        Button_27                       matlab.ui.control.Button
        Button_28                       matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        SelectedFiles = {};
        FilesPath     = {};
        
        Samples  = [];
        metaData = struct('File', [], 'Data', [], 'Samples', [])
        specData = [];
        filtData = [];
        
        dialogWin = [];
        
        Tree2_WarnCount = 0;
        Tree2_CritCount = 0;
        
        nodesTable = [];
        nodesInfo  = 0;
        
        htmlSet = [];
        
        fig   = [];
        axes1 = [];
        axes2 = [];
        axes3 = [];
        cBar1 = [];
        line1 = [];
        
        timeIndex = 0;
        
        plotFlag   = 0;
        axesFlag   = 0;
        reloadFlag = 1;
        
        traceInfo = struct('SelectedNode', 1, 'Mode', [0, 0, 0], 'plotLayout', 0);
        cTraces = [0.07, 0.07, 0.75; ... % color1 (MinHold)
                   0.39, 0.83, 0.07; ... % color2 (Mode, Median or Mean)
                   1.00, 0.07, 0.65; ... % color3 (MaxHold)
                   0.93, 0.69, 0.13];    % color4 (ClearWrite)
        cMapp   = [1 1; ... % MinHold
                   2 2; ... % Mode
                   3 2; ... % Median
                   4 2; ... % Mean
                   5 3];    % MaxHold
        
        occTHR;
        
        titleStr;
        
    end
    
    
    methods (Access = private)
        
        function Fcn_MetaDataReader(app)
            
            if isempty(app.metaData(1).File)
                auxIndex = 1;
            else
                auxIndex = length(app.metaData)+1;
            end
        
            for ii = 1:length(app.SelectedFiles)
                app.metaData(ii).File = [app.FilesPath{ii} app.SelectedFiles{ii}];
            end
            
            % Progress window
            d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on');
            
            for ii = auxIndex:length(app.metaData)
                if length(app.SelectedFiles{ii}) <= 50
                    d.Message = sprintf('Em andamento a leitura de metadados do arquivo:\n• %s\n\n%d de %d', app.SelectedFiles{ii}, ii, length(app.metaData));
                else
                    d.Message = sprintf('Em andamento a leitura de metadados do arquivo:\n• %s\n\n%d de %d', [app.SelectedFiles{ii}(1:48) '...'], ii, length(app.metaData));
                end
                
                app.metaData(ii).Data = Fcn_BinFileReaderV4(app.metaData(ii).File, 'MetaData', []);
                app.metaData(ii).Samples = [app.metaData(ii).Data.Samples]';
            end
            
        end
        
        
        function Fcn_SpecDataReader(app)
            
            t = zeros(1,3);
            tic
            % Organização dos fluxos de dados e pré-alocação
            Fcn_SpecDataReader_Aux1(app)
            t(1) = toc;
            
            tic
            % Leitura dos dados de espectro
            auxValue1 = length(app.metaData);
            for ii = 1:auxValue1
                if length(app.SelectedFiles{ii}) <= 50
                    app.dialogWin.Message = sprintf('Em andamento a leitura de dados do espectro do arquivo:\n• %s\n\n%d de %d', app.SelectedFiles{ii}, ii, auxValue1);
                else
                    app.dialogWin.Message = sprintf('Em andamento a leitura de dados do espectro do arquivo:\n• %s\n\n%d de %d', [app.SelectedFiles{ii}(1:48) '...'], ii, auxValue1);
                end
                
                   SpecInfo = Fcn_BinFileReaderV4(app.metaData(ii).File, 'SpecData', app.metaData(ii));
                auxSpecInfo = rmfield(SpecInfo, {'Samples', 'Data', 'gpsString', 'gpsFlag', 'Blocks'});
                
                auxSpecData = rmfield(app.specData, {'Samples', 'Data', 'gpsString', 'gpsFlag', 'Blocks'});
                
                for jj = 1:length(SpecInfo)
                    for kk = 1:length(app.specData)
                        if isequal(auxSpecData(kk), auxSpecInfo(jj))
                            Index1 = kk;
                            break
                        end
                    end
                                        
                    auxValue2 = sum(app.Samples(Index1, 1:ii));
                    if ii == 1
                        Index2 = 1;
                    else
                        Index2 = sum(app.Samples(Index1, 1:(ii-1)))+1;
                    end
                    
                    app.specData(Index1).Data{1}(1,Index2:auxValue2) = SpecInfo(jj).Data{1};
                    app.specData(Index1).Data{2}(:,Index2:auxValue2) = SpecInfo(jj).Data{2};
                end
            end
            app.specData = rmfield(app.specData, 'Blocks');            
            t(2) = toc;
            
            tic
            % Manipulações acessórias
            app.dialogWin.Message = sprintf('Em andamento manipulações acessórias, como aferição de parâmetros estatísticos e identificação de fluxo(s) de ocupação relacionado(s).');
            Fcn_SpecDataReader_Aux2(app)
            t(3) = toc
            
        end
        
        
        function Fcn_SpecDataReader_Aux1(app)
            
            global Samples1
            
            % Organização dos fluxos de dados
            for ii = 1:length(app.metaData)
                if ii == 1
                    app.specData = app.metaData(1).Data;
                    app.Samples  = app.metaData(1).Samples;
                else
                       SpecInfo = app.metaData(ii).Data;
                    auxSpecInfo = rmfield(SpecInfo, {'Samples', 'gpsString', 'gpsFlag', 'Blocks'});
                    auxSamples  = app.metaData(ii).Samples;
                    
                    for kk = 1:length(SpecInfo)
                        auxIndex2 = find([app.specData.ThreadID] == SpecInfo(kk).ThreadID);
                        
                        if isempty(auxIndex2)
                            app.specData(end+1)    = SpecInfo(kk);
                            app.Samples(end+1, ii) = auxSamples(kk);
                        else
                            for ll = auxIndex2
                                auxSpecData = rmfield(app.specData(ll), {'Samples', 'gpsString', 'gpsFlag', 'Blocks'});
                                
                                if isequal(auxSpecData, auxSpecInfo(kk))
                                    app.Samples(ll, ii) = auxSamples(kk);
                                    break
                                end
                                
                                if ll == auxIndex2(end)
                                    app.specData(end+1)    = SpecInfo(kk);
                                    app.Samples(end+1, ii) = auxSamples(kk);
                                end
                            end
                        end
                    end
                end
            end
            
            % Pré-alocação
            totalSamples = sum(app.Samples, 2);
            
            Samples1 = app.Samples;
            for jj = 1:length(app.specData)
                app.specData(jj).Samples = totalSamples(jj);
                app.specData(jj).Data    = {repmat(datetime(0,0,0), 1, totalSamples(jj)), ...
                                            zeros(app.specData(jj).MetaData.NDATA, totalSamples(jj), 'single')};
            end
            
        end
        
        
        function Fcn_SpecDataReader_Aux2(app)
            
%             % Fluxos de dados ordenados por ThreadID
%             if ~issorted([app.specData.ThreadID])
%                 [~, auxIndex] = sort([app.specData.ThreadID]);
%                 app.specData  = app.specData(auxIndex);
%             end
            
            
            % Fluxos de dados ordenados temporalmente
            for ii = 1:length(app.specData)
                if ~issorted(app.specData(ii).Data{1})
                    [app.specData(ii).Data{1}, auxIndex] = sort(app.specData(ii).Data{1});
                    app.specData(ii).Data{2}             = app.specData(ii).Data{2}(:,auxIndex);
                end
            end
            
            
            % Estatística e mapeamento dos fluxos de ocupação
            for ii = 1:length(app.specData)
                % Estatística
                app.specData(ii).statsData = [min(app.specData(ii).Data{2}, [], 2), ...
                                              mode(app.specData(ii).Data{2}, 2),    ...
                                              median(app.specData(ii).Data{2}, 2),  ...
                                              mean(app.specData(ii).Data{2}, 2),    ...
                                              max(app.specData(ii).Data{2}, [], 2)];
                
                % Fluxos de ocupação
                if ismember(app.specData(ii).MetaData.DataType, [60, 61, 63, 64, 67, 68])
                    switch app.specData(ii).MetaData.DataType
                        case {60, 61}; auxValue = 62;
                        case {63, 64}; auxValue = 65;
                        case {67, 68}; auxValue = 69;
                    end
                    auxIndex = [];
                    for jj = 1:length(app.specData)
                        if app.specData(jj).MetaData.DataType == auxValue
                            auxIndex = [auxIndex, jj];
                        end
                    end

                    if isempty(auxIndex)
                        continue
                    end
                    
                    app.specData(ii).occRelated = [];
                    app.specData(ii).occFlag = [];
                    for kk = auxIndex
                        logEvaluation = strcmp(app.specData(ii).Node, app.specData(kk).Node)         & ...
                                        app.specData(ii).MetaData.F0 == app.specData(kk).MetaData.F0 & ...
                                        app.specData(ii).MetaData.F1 == app.specData(kk).MetaData.F1 & ...
                                        app.specData(ii).MetaData.DTYPE == app.specData(kk).MetaData.DTYPE & ...
                                        app.specData(ii).MetaData.NDATA == app.specData(kk).MetaData.NDATA & ...
                                        strcmp(app.specData(ii).ScriptName, app.specData(kk).ScriptName);
            
                        if logEvaluation
                            app.specData(ii).occRelated = [app.specData(ii).occRelated, kk];
                        end
                    end
                    
                    if ~isempty(app.specData(ii).occRelated)
                        app.specData(ii).occFlag = app.specData(ii).occRelated(1);
                    end
                end
            end
            
        end
   
        
        function Fcn_Tree1(app)

            delete(app.Tree_11.Children);
            
            app.Button_12.Enable = 1;
            app.Button_15.Enable = 1;
            
            app.Label_13.Enable = 0;
            set(app.Grid_12.Children, 'Enable', 0);
            
            if app.Tree_21.Enable
                app.Warning_21.Visible = 1;
            else
                app.Warning_21.Visible = 0;
            end
            
            value = length(app.SelectedFiles);
            if value
                for ii = 1:value
                    if contains(app.metaData(ii).Data(1).Node, 'RFeye', 'IgnoreCase', true)
                        imageIcon = 'CRFS.png';
%                     elseif contains(app.metaData(ii).Data(1).Node, '3A', 'IgnoreCase', true)
%                         imageIcon = 'R&S Icon.png';
                    else
                        imageIcon = 'R&S Icon.png';
                    end
                    
                    uitreenode(app.Tree_11, 'Text', app.SelectedFiles{ii}, 'NodeData', ii, 'Icon', imageIcon);
                    
                end
                
            else
                app.metaData = struct('File', [], 'Data', [], 'Samples', []);
                
                app.Button_12.Enable = 0;
                app.Button_15.Enable = 0;
            end
            app.Label_12.Text = sprintf('%d arquivo(s) selecionado(s)', value);
            
        end
        
        
        function Fcn_Tree2(app)
            
            delete(app.Tree_21.Children);
            
            app.Tree2_WarnCount = 0;
            app.Tree2_CritCount = 0;
            
            app.Button_24.Enable = 0;
            app.Button_25.Enable = 0;
            app.Button_26.Enable = 0;
            
            app.Button_26.Value  = 0;
            
            for ii = 1:length(app.specData)
                allNodes{ii,1} = app.specData(ii).Node;
            end
            [uniqueNodes, ~, ic] = unique(allNodes);
            a_counts = accumarray(ic, 1);
            
            for ii = 1:length(uniqueNodes)
                if contains(uniqueNodes{ii}, 'RFeye', 'IgnoreCase', true)
                    imageIcon = 'CRFS.png';
                elseif contains(uniqueNodes{ii}, '3A', 'IgnoreCase', true)
                    imageIcon = 'R&S Icon.png';
                end
                Category(ii) = uitreenode(app.Tree_21, 'Text', uniqueNodes{ii}, 'NodeData', uniqueNodes{ii}, 'Icon', imageIcon);
                
                auxIndex = find(ic == ii);
                for jj = 1:a_counts(ii)
                    if ismember(app.specData(auxIndex(jj)).MetaData.DataType, [60, 61, 63, 64, 67, 68])
                        Attributes(jj) = uitreenode(Category(ii), 'Text', sprintf('ThreadID %d: %.3f - %.3f MHz', ...
                                                                          app.specData(auxIndex(jj)).ThreadID, ...
                                                                          1e-6*app.specData(auxIndex(jj)).MetaData.F0, ...
                                                                          1e-6*app.specData(auxIndex(jj)).MetaData.F1), 'NodeData', auxIndex(jj));
                                            
                        uitreenode(Attributes(jj), 'Text', ['Descrição: ' sprintf('"%s"', app.specData(auxIndex(jj)).Description)], 'NodeData', auxIndex(jj));
    
                        Temp1 = uitreenode(Attributes(jj), 'Text', [sprintf('%d', app.specData(auxIndex(jj)).Samples) ' varreduras'], 'NodeData', auxIndex(jj));
                        uitreenode(Temp1, 'Text', [datestr(app.specData(auxIndex(jj)).Data{1}(1))   ' (Início)'], 'NodeData', auxIndex(jj));
                        uitreenode(Temp1, 'Text', [datestr(app.specData(auxIndex(jj)).Data{1}(end)) ' (Fim)'   ], 'NodeData', auxIndex(jj));
                        
                        
                        % Informações complementares
                        Temp2 = uitreenode(Attributes(jj), 'Text', 'Informações complementares', 'NodeData', auxIndex(jj));
                        if isempty(app.specData(auxIndex(jj)).MetaData.AntennaID)
                            app.Tree2_WarnCount = app.Tree2_WarnCount + 1;
                            
                            uitreenode(Temp2, 'Text', sprintf('Antena: %d', app.specData(auxIndex(jj)).MetaData.AntennaID), 'NodeData', auxIndex(jj), 'Icon', 'treeAlarm3.png');
                        else
                            uitreenode(Temp2, 'Text', sprintf('Antena: %d', app.specData(auxIndex(jj)).MetaData.AntennaID), 'NodeData', auxIndex(jj));
                        end
                        
                        if app.specData(auxIndex(jj)).gpsFlag == 0
                            uitreenode(Temp2, 'Text', sprintf('GPS: %s', app.specData(auxIndex(jj)).gpsString), 'NodeData', auxIndex(jj));
                        elseif app.specData(auxIndex(jj)).gpsFlag == 1
                            app.Tree2_WarnCount = app.Tree2_WarnCount + 1;
                            app.Tree2_CritCount = app.Tree2_CritCount + 1;
                            
                            uitreenode(Temp2, 'Text', 'GPS', 'NodeData', auxIndex(jj), 'Icon', 'treeAlarm2.png');
                        else
                            uitreenode(Temp2, 'Text', sprintf('GPS: %s', app.specData(auxIndex(jj)).gpsString), 'NodeData', auxIndex(jj), 'Icon', 'Manual.png');
                        end
                        
                        if isempty(app.specData(auxIndex(jj)).MetaData.Resolution)
                            app.Tree2_WarnCount = app.Tree2_WarnCount + 1;
                            
                            uitreenode(Temp2, 'Text', 'Resolução', 'NodeData', auxIndex(jj), 'Icon', 'treeAlarm3.png');
                        else
                            uitreenode(Temp2, 'Text', sprintf('Resolução: %.3f kHz', 1e-3*app.specData(auxIndex(jj)).MetaData.Resolution), 'NodeData', auxIndex(jj));
                        end
                        
                        uitreenode(Temp2, 'Text', sprintf('Sweep points: %d', app.specData(auxIndex(jj)).MetaData.NDATA), 'NodeData', auxIndex(jj));
                        uitreenode(Temp2, 'Text', sprintf('Step Width: %.3f kHz', 1e-3*(app.specData(auxIndex(jj)).MetaData.F1 - app.specData(auxIndex(jj)).MetaData.F0)/(app.specData(auxIndex(jj)).MetaData.NDATA-1)), 'NodeData', auxIndex(jj));
                        
                        
                        % Ocupação
                        Temp3 = uitreenode(Attributes(jj), 'Text', 'Fluxo(s) de ocupação', 'NodeData', auxIndex(jj));
                        if isempty(app.specData(auxIndex(jj)).occRelated)
                            app.Tree2_WarnCount = app.Tree2_WarnCount + 1;
                            
                            uitreenode(Temp3, 'Text', 'Ocupação a ser aferida', 'NodeData', auxIndex(jj), 'Icon', 'treeAlarm3.png');
                        else
                            if app.specData(auxIndex(jj)).occFlag ~= 0
                                uitreenode(Temp3, 'Text', 'Ocupação a ser aferida', 'NodeData', auxIndex(jj));
                            else
                                uitreenode(Temp3, 'Text', 'Ocupação a ser aferida', 'NodeData', auxIndex(jj), 'Icon', 'Manual.png');
                            end
                            
                            auxIndex2 = app.specData(auxIndex(jj)).occRelated;
                            for kk = auxIndex2
                                switch app.specData(kk).MetaData.DTYPE
                                    case 0; auxStr = 'dBm';
                                    case 1; auxStr = 'dBµV/m';
                                end
                                
                                if (app.specData(auxIndex(jj)).occFlag == kk) && (kk == auxIndex2(1))
                                    uitreenode(Temp3, 'Text', sprintf('Threshold: %d %s (ThreadID %d)', app.specData(kk).MetaData.Threshold, auxStr, app.specData(kk).ThreadID), 'NodeData', auxIndex(jj), 'Icon', 'Auto.png');
                                else
                                    if app.specData(auxIndex(jj)).occFlag == kk
                                        uitreenode(Temp3, 'Text', sprintf('Threshold: %d %s (ThreadID %d)', app.specData(kk).MetaData.Threshold, auxStr, app.specData(kk).ThreadID), 'NodeData', auxIndex(jj), 'Icon', 'Manual.png');
                                    else
                                        uitreenode(Temp3, 'Text', sprintf('Threshold: %d %s (ThreadID %d)', app.specData(kk).MetaData.Threshold, auxStr, app.specData(kk).ThreadID), 'NodeData', auxIndex(jj));
                                    end
                                end                     
                            end
                        end
                    end
                end
            end
            
            if app.Tree2_CritCount == 0
                app.Label_22.Text = sprintf('%d alerta(s)', app.Tree2_WarnCount);
                app.Label_22.FontColor = 'black';
            else
                app.Label_22.Text = sprintf('%d alerta(s), sendo %d crítico(s)', app.Tree2_WarnCount, app.Tree2_CritCount);
                app.Label_22.FontColor = 'red';
            end
            
        end
        
        
        function Fcn_Tree3(app)
            
            delete(app.Tree_22.Children);
            
            if ~isempty(app.filtData)
                if app.Button_23.Value
                    app.Button_27.Enable = 1;
                end
            else
                return
            end
            
            for ii = 1:length(app.filtData)
                allNodes{ii,1} = app.specData(app.filtData(ii)).Node;
            end
            [uniqueNodes, ~, ic] = unique(allNodes);
            a_counts = accumarray(ic, 1);
            
            for ii = 1:length(uniqueNodes)
                if contains(uniqueNodes{ii}, 'RFeye', 'IgnoreCase', true)
                    imageIcon = 'CRFS.png';
%                 elseif contains(uniqueNodes{ii}, 'EB500', 'IgnoreCase', true)
                else
                    imageIcon = 'R&S Icon.png';
                end
                Category(ii) = uitreenode(app.Tree_22, 'Text', uniqueNodes{ii}, 'NodeData', uniqueNodes{ii}, 'Icon', imageIcon);
                
                auxIndex1 = find(ic == ii);
                for jj = 1:a_counts(ii)
                    auxIndex2 = app.filtData(auxIndex1(jj));
                    
                    if ismember(app.specData(auxIndex2).MetaData.DataType, [60, 61, 63, 64, 67, 68])
                        Attributes(jj) = uitreenode(Category(ii), 'Text', sprintf('ThreadID %d: %.3f - %.3f MHz', ...
                                                                          app.specData(auxIndex2).ThreadID, ...
                                                                          1e-6*app.specData(auxIndex2).MetaData.F0, ...
                                                                          1e-6*app.specData(auxIndex2).MetaData.F1), 'NodeData', auxIndex2);
    
                        Temp1 = uitreenode(Attributes(jj), 'Text', [sprintf('%d', app.specData(auxIndex2).Samples) ' varreduras'], 'NodeData', auxIndex2);
                        uitreenode(Temp1, 'Text', [datestr(app.specData(auxIndex2).Data{1}(1))   ' (Início)'], 'NodeData', auxIndex2);
                        uitreenode(Temp1, 'Text', [datestr(app.specData(auxIndex2).Data{1}(end)) ' (Fim)'   ], 'NodeData', auxIndex2);
                        
                        % Ocupação
                        Temp3 = uitreenode(Attributes(jj), 'Text', 'Fluxo(s) de ocupação', 'NodeData', auxIndex2);
                        if isempty(app.specData(auxIndex2).occRelated)
                            uitreenode(Temp3, 'Text', 'Ocupação a ser aferida', 'NodeData', auxIndex2, 'Icon', 'treeAlarm3.png');
                        else
                            if app.specData(auxIndex2).occFlag ~= 0
                                uitreenode(Temp3, 'Text', 'Ocupação a ser aferida', 'NodeData', auxIndex2);
                            else
                                uitreenode(Temp3, 'Text', 'Ocupação a ser aferida', 'NodeData', auxIndex2, 'Icon', 'Manual.png');
                            end
                            
                            auxIndex3 = app.specData(auxIndex2).occRelated;
                            for kk = auxIndex3
                                switch app.specData(kk).MetaData.DTYPE
                                    case 0; auxStr = 'dBm';
                                    case 1; auxStr = 'dBµV/m';
                                end
                                
                                if (app.specData(auxIndex2).occFlag == kk) && (kk == auxIndex3(1))
                                    uitreenode(Temp3, 'Text', sprintf('Threshold: %d %s (ThreadID %d)', app.specData(kk).MetaData.Threshold, auxStr, app.specData(kk).ThreadID), 'NodeData', auxIndex2, 'Icon', 'Auto.png');
                                else
                                    if app.specData(auxIndex2).occFlag == kk
                                        uitreenode(Temp3, 'Text', sprintf('Threshold: %d %s (ThreadID %d)', app.specData(kk).MetaData.Threshold, auxStr, app.specData(kk).ThreadID), 'NodeData', auxIndex2, 'Icon', 'Manual.png');
                                    else
                                        uitreenode(Temp3, 'Text', sprintf('Threshold: %d %s (ThreadID %d)', app.specData(kk).MetaData.Threshold, auxStr, app.specData(kk).ThreadID), 'NodeData', auxIndex2);
                                    end
                                end                     
                            end
                        end
                    end
                end
            end
            Fcn_Tree3_Aux1(app)
            
        end
        
        
        function Fcn_Tree3_Aux1(app)
            
            StartDate = [];
            StopDate  = [];
            for ii = 1:length(app.filtData)
                auxStartDate        = app.specData(app.filtData(ii)).Data{1}(1);
                auxStartDate.Format = 'dd-MMM-yyyy';
                
                auxStopDate         = app.specData(app.filtData(ii)).Data{1}(end);
                auxStopDate.Format  = 'dd-MMM-yyyy';
                
                StartDate = [StartDate auxStartDate];
                StopDate  = [StopDate  auxStopDate ];
            end
            
            app.Report_DatePicker1.Limits = [min(StartDate) max(StopDate)];
            app.Report_DatePicker1.Value  = app.Report_DatePicker1.Limits(1);
            
            app.Report_DatePicker2.Limits = app.Report_DatePicker1.Limits;
            app.Report_DatePicker2.Value  = app.Report_DatePicker2.Limits(2);
            
            app.Report_Spinner1.Value = 0;
            app.Report_Spinner2.Value = 0;
            app.Report_Spinner3.Value = 23;
            app.Report_Spinner4.Value = 59;
            
        end
        
        
        function Fcn_Plot(app)
            
            Fcn_Plot_Aux1(app) % Layout.
            Fcn_Plot_Aux2(app) % Edição de informações da figura e dos eixos.
            Fcn_Plot_Aux3(app) % Inicialização do(s) fluxo(s) a plotar.
            
            while app.timeIndex < app.specData(app.traceInfo.SelectedNode).Samples
                if ~app.plotFlag
                    return
                elseif app.axesFlag
                    if app.traceInfo.SelectedNode ~= app.Tree_21.SelectedNodes.NodeData
                        app.timeIndex = 0;
                    end
                    
                    Fcn_Plot_Aux1(app)
                    Fcn_Plot_Aux2(app)
                    Fcn_Plot_Aux3(app)
                end
                app.timeIndex = app.timeIndex+1;

% %             TimeStamp and Slider Update.    
                app.titleStr.String = datestr(app.specData(app.traceInfo.SelectedNode).Data{1}(app.timeIndex), 'dd/mm/yyyy HH:MM:SS');
                app.Slider31.Value  = 100 * app.timeIndex/app.specData(app.traceInfo.SelectedNode).Samples;
                
% %             Axes 1: Spectrum Plot.
                app.axes1.Children(end).YData = app.specData(app.traceInfo.SelectedNode).Data{2}(:, app.timeIndex)';
                
                auxIndex = find(app.traceInfo.Mode ~= 0);
                if app.RadioButton_02.Value
                    jj = 0;
                    for ii = auxIndex
                        jj = jj+1;
                        
                        switch app.traceInfo.Mode(ii)
                            case 1
                                app.axes1.Children(end-jj).YData = min(app.axes1.Children(end-jj).YData, app.axes1.Children(end).YData);
                            case 4
                                app.axes1.Children(end-jj).YData = ((app.Value_01.Value-1)*app.axes1.Children(end-jj).YData ...
                                                                    + app.axes1.Children(end).YData)/app.Value_01.Value;
                            case 5
                                app.axes1.Children(end-jj).YData = max(app.axes1.Children(end-jj).YData, app.axes1.Children(end).YData);
                        end
                    end
                end
                
%             Axes2: OCC Plot.
                if app.CheckBox_04.Value
                    switch app.THR_Type.Value
                        case 'Linear'
                            app.axes2.Children(1).YData = ((app.Value31_3.Value-1)*app.axes2.Children(1).YData ...
                                                           +100*single(app.axes1.Children(end).YData > app.THR_Value.Value))/app.Value31_3.Value;
                            app.axes2.Children(2).YData = max(app.axes2.Children(1).YData, app.axes2.Children(2).YData);
                        case 'Estimativa do piso de ruído acrescida de OffSet'
                            app.axes2.Children(1).YData = ((app.Value31_3.Value-1)*app.axes2.Children(1).YData ...
                                                           +100*single(app.axes1.Children(end).YData > app.occTHR))/app.Value31_3.Value;
                            app.axes2.Children(2).YData = max(app.axes2.Children(1).YData, app.axes2.Children(2).YData);
                    end
                end
                
% %             Axes3: WaterFall Plot.
                if app.traceInfo.plotLayout == 2
                    app.line1.Position(2) = .1+.4*(app.timeIndex/app.specData(app.traceInfo.SelectedNode).Samples);
                elseif app.traceInfo.plotLayout == 3
                    app.line1.Position(2) = .1+.3*(app.timeIndex/app.specData(app.traceInfo.SelectedNode).Samples);
                end
                drawnow
                
% %             Reload Flag.
                if app.timeIndex == app.specData(app.traceInfo.SelectedNode).Samples
                    if app.reloadFlag
                        app.timeIndex = 0;
                    else
                        Layout_Playback(app)
                    end
                end
            end
            app.timeIndex      = 0;
            app.Slider31.Value = 0;
            
        end
        
        
        function Fcn_Plot_Aux1(app)
            
            delete(app.axes1.Children)
            delete(app.axes2.Children)
            delete(app.axes3.Children)
            
            linkaxes([app.axes1, app.axes2, app.axes3], 'off')
            
            app.fig.Visible = 1;
            
            % Análise do novo fluxo selecionado.
            if app.traceInfo.SelectedNode ~= app.Tree_21.SelectedNodes.NodeData
                app.timeIndex = 0;
            end
            app.traceInfo.SelectedNode = app.Tree_21.SelectedNodes.NodeData;
            
            if length(app.specData(app.traceInfo.SelectedNode).Data{1}) > 1
                app.CheckBox_05.Enable = 1;
            else
                app.CheckBox_05.Enable = 0;
                app.CheckBox_05.Value  = 0;
                if ismember(app.traceInfo.plotLayout, [2, 3])
                    app.traceInfo.plotLayout = 0;
                end
            end
            
% %         Axes layout, xLabel and yLabel.
            switch app.traceInfo.plotLayout
                case 0
                    app.axes1.InnerPosition = [.08 .1 .85 .82];
                    app.axes2.InnerPosition = [0 0 0 0];
                    app.axes3.InnerPosition = [0 0 0 0];
                    
                    app.cBar1.Position = [0 0 0 0];
                    app.line1.Position = [0 0 0 0];
                    
                    app.axes1.XTickLabelMode = 'auto';
                    xlabel(app.axes1, 'Frequência (MHz)', 'Color', [0.8,0.8,0.8], 'FontName', 'Calibri', 'FontSize', 9, 'FontSmoothing', 'off')
                    
                case 1
                    app.axes1.InnerPosition = [.08 .52 .85 .40];
                    app.axes2.InnerPosition = [.08 .1  .85 .40];
                    app.axes3.InnerPosition = [0 0 0 0];
                    
                    app.cBar1.Position = [0 0 0 0];
                    app.line1.Position = [0 0 0 0];
                    
                    app.axes1.XTickLabel = {};
                    
                    xlabel(app.axes1, '')
                    xlabel(app.axes2, 'Frequência (MHz)', 'Color', [0.8,0.8,0.8], 'FontName', 'Calibri', 'FontSize', 9, 'FontSmoothing', 'off')
                    
                    linkaxes([app.axes1, app.axes2], 'x')

                case 2
                    app.axes1.InnerPosition = [.08 .52 .85 .40];
                    app.axes2.InnerPosition = [0 0 0 0];
                    app.axes3.InnerPosition = [.08 .1  .85 .40];
                    
                    app.cBar1.Position = [0.9405, 0.1, 0.0145, 0.1];
                    app.line1.Position = [.08 .1 .85 0];
                    
                    app.axes1.XTickLabel = {};
                    
                    xlabel(app.axes1, '')
                    xlabel(app.axes2, '')
                    xlabel(app.axes3, 'Frequência (MHz)', 'Color', [0.8,0.8,0.8], 'FontName', 'Calibri', 'FontSize', 9, 'FontSmoothing', 'off')
                    
                    linkaxes([app.axes1, app.axes3], 'x')

                case 3
                    app.axes1.InnerPosition = [.08 .62 .85 .30];
                    app.axes2.InnerPosition = [.08 .42 .85 .18];
                    app.axes3.InnerPosition = [.08 .1  .85 .30];
                    
                    app.cBar1.Position = [0.9405, 0.1, 0.0145, 0.1];
                    app.line1.Position = [.08 .1 .85 0];
                    
                    app.axes1.XTickLabel = {};
                    app.axes2.XTickLabel = {};
                    
                    xlabel(app.axes1, '')
                    xlabel(app.axes2, '')
                    xlabel(app.axes3, 'Frequência (MHz)', 'Color', [0.8,0.8,0.8], 'FontName', 'Calibri', 'FontSize', 9, 'FontSmoothing', 'off')
                    
                    linkaxes([app.axes1, app.axes2, app.axes3], 'x')
            end
            
            unit = '';
            switch app.specData(app.traceInfo.SelectedNode).MetaData.DTYPE
                case 0; unit = 'dBm';
                case 1; unit = 'dBμV/m';
            end
            ylabel(app.axes1, ['Nível (' unit ')'], 'Color', [0.8,0.8,0.8], 'FontName', 'Calibri', 'FontSize', 9);
            
        end
        
        
        function Fcn_Plot_Aux2(app)
            
% %         Figure name, title, subtitle and "x/y limits".
            app.fig.Name = app.specData(app.traceInfo.SelectedNode).Node;
            
            subtitleStr = '';
            if ~isempty(app.specData(app.traceInfo.SelectedNode).gpsString)
                subtitleStr = sprintf('GPS: %s. ', app.specData(app.traceInfo.SelectedNode).gpsString);
            end
            
            if ~isempty(app.specData(app.traceInfo.SelectedNode).MetaData.Detector)
                subtitleStr = [subtitleStr sprintf('%s/', app.specData(app.traceInfo.SelectedNode).MetaData.Detector)];
            end

            switch app.specData(app.traceInfo.SelectedNode).MetaData.TraceMode
                case 0
                    traceMode = 'ClearWrite';
                case 1
                    if contains(app.specData(app.traceInfo.SelectedNode).Node, 'RFeye', 'IgnoreCase', true)
                        traceMode = 'Average';
                    else
                        traceMode = 'Média (vídeo)';
                    end
                case 2
                    if contains(app.specData(app.traceInfo.SelectedNode).Node, 'RFeye', 'IgnoreCase', true)
                        traceMode = 'Peak';
                    else
                        traceMode = 'MaxHold';
                    end
                case 3
                    traceMode = 'MinHold';
            end
            subtitleStr = [subtitleStr sprintf('%s. ', traceMode)];
                                    
            if ~isempty(app.specData(app.traceInfo.SelectedNode).MetaData.Resolution)
                subtitleStr = [subtitleStr sprintf('RBW: %.3f kHz. ', app.specData(app.traceInfo.SelectedNode).MetaData.Resolution/1000)];
            end        
            
            subtitleStr = [subtitleStr sprintf('SWP: %d.', app.specData(app.traceInfo.SelectedNode).MetaData.NDATA)];
            
            app.titleStr = title(app.axes1, datestr(app.specData(app.traceInfo.SelectedNode).Data{1}(1), 'dd/mm/yyyy HH:MM:SS'), subtitleStr, ...
                                 'Color', [0.93,0.69,0.13], 'FontName', 'Calibri', 'FontSize', 9, 'FontWeight', 'normal', 'FontSmoothing', 'off');
            
% %         Axes1 (Spectrum plot)
            auxValue = min(app.specData(app.traceInfo.SelectedNode).statsData(:,1));
            downYLim = auxValue - mod(auxValue, 10);
        
            auxValue = max(app.specData(app.traceInfo.SelectedNode).statsData(:,5));
            upYLim   = auxValue - mod(auxValue, 10) + 10;
            
            set(app.axes1, 'XLim', [app.specData(app.traceInfo.SelectedNode).MetaData.F0, app.specData(app.traceInfo.SelectedNode).MetaData.F1], ...
                           'YLim', [downYLim, upYLim]);
            
% %          Axes 2 (OCC plot)
            if app.CheckBox_04.Value
                app.fig.Children(1).Visible = 0;
                app.line1.Visible = 0;
                
                set(app.axes2, 'XLim', [app.specData(app.traceInfo.SelectedNode).MetaData.F0, app.specData(app.traceInfo.SelectedNode).MetaData.F1], ...
                               'YLim' , [0, 100]);
                ylabel(app.axes2, 'Ocupação (%)', 'Color', [0.8,0.8,0.8], 'FontName', 'Calibri', 'FontSize', 9, 'FontSmoothing', 'off');
            end
            
% %          Axes 2 (WaterFall plot)
            if app.CheckBox_05.Value
                app.fig.Children(1).Visible = 1;
                app.line1.Visible = 1;
                
                if diff(app.axes1.YLim) > 100
                    Li = (app.axes1.YLim(1)+diff(app.axes1.YLim)-100);
                    Ls = app.axes1.YLim(2);
                else
                    Li = app.axes1.YLim(1);
                    Ls = app.axes1.YLim(2);
                end
                caxis(app.axes3, [Li Ls])
                colormap(app.axes3, 'jet');
                
                set(app.cBar1, 'Limits', [Li, Ls], 'Ticks', [Li, Ls], 'TickLabels', {num2str(Li), num2str(Ls)});                
                set(app.axes3, 'XLim', [app.specData(app.traceInfo.SelectedNode).MetaData.F0, app.specData(app.traceInfo.SelectedNode).MetaData.F1]);
                ylabel(app.axes3, 'Amostras', 'Color', [0.8,0.8,0.8], 'FontName', 'Calibri', 'FontSize', 9, 'FontSmoothing', 'off');
            end
                
        end
        
        
        function Fcn_Plot_Aux3(app)
            
            x = linspace(app.specData(app.traceInfo.SelectedNode).MetaData.F0, app.specData(app.traceInfo.SelectedNode).MetaData.F1, app.specData(app.traceInfo.SelectedNode).MetaData.NDATA);
            
            plot(app.axes1, x, app.specData(app.traceInfo.SelectedNode).Data{2}(:, app.timeIndex+1), 'Color', app.cTraces(4,:));
            
            auxIndex1 = find(app.traceInfo.Mode ~= 0);
            if ~isempty(auxIndex1)
                if app.RadioButton_01.Value
                    for ii = auxIndex1
                        plot(app.axes1, x, app.specData(app.traceInfo.SelectedNode).statsData(:, app.traceInfo.Mode(ii)), 'Color', app.cTraces(ii,:));
                    end
                    
                else
                    for ii = auxIndex1
                        plot(app.axes1, x, app.specData(app.traceInfo.SelectedNode).Data{2}(:, app.timeIndex+1), 'Color', app.cTraces(ii,:));
                    end
                end
            end

            if app.CheckBox_04.Value
                switch app.THR_Type.Value
                    case 'Linear'
                        plot(app.axes1, [x(1) x(end)], [app.THR_Value.Value app.THR_Value.Value], 'Color', 'red', 'LineStyle', '-.', 'LineWidth', 1);
                        text(app.axes1, x(end), app.THR_Value.Value, 'THR', 'FontName', 'Calibri', 'FontSize', 9, 'Color', 'red')

                    case 'Estimativa do piso de ruído acrescida de OffSet'
                        switch app.Report_Value6_2.Value
                            case 'Moda'         ; auxIndex2 = 2;
                            case 'Mediana'      ; auxIndex2 = 3;
                            case 'Média (vídeo)'; auxIndex2 = 4;
                        end
                        app.occTHR = app.specData(app.traceInfo.SelectedNode).statsData(:, auxIndex2)';
                        
                        auxMatrix = sort(app.specData(app.traceInfo.SelectedNode).Data{2});
                        auxMatrix = auxMatrix(1:ceil(app.Report_Value8_2.Value * app.specData(app.traceInfo.SelectedNode).MetaData.NDATA / 100), :);
                        noiseMean = mean(auxMatrix,    'all');
                        noiseStd  =  std(auxMatrix, 1, 'all');
                        
                        switch app.Report_Value6_3.Value
                            case '68'; auxValue = 1;
                            case '95'; auxValue = 2;
                            case '99'; auxValue = 3;
                        end
                        app.occTHR(app.occTHR < noiseMean - auxValue*noiseStd) = noiseMean - auxValue*noiseStd;
                        app.occTHR(app.occTHR > noiseMean + auxValue*noiseStd) = noiseMean + auxValue*noiseStd;
                        
                        app.occTHR = app.occTHR + app.Report_Value5_3.Value;
                        
                        plot(app.axes1, x, app.occTHR, 'Color', 'red', 'LineStyle', '-', 'LineWidth', 1);
                        text(app.axes1, x(end), double(app.occTHR(end)), 'THR', 'FontName', 'Calibri', 'FontSize', 9, 'Color', 'red')
                        
                end
                
                plot(app.axes2, x, zeros(1, length(app.axes1.Children(end).YData), 'single'), 'Color', app.cTraces(3,:));   % MaxHold
                plot(app.axes2, x, zeros(1, length(app.axes1.Children(end).YData), 'single'), 'Color', app.cTraces(2,:));   % Média
            end
            
            if app.CheckBox_05.Value
                [auxRows, auxColumns] = size(app.specData(app.traceInfo.SelectedNode).Data{2});      
                auxSize = auxRows .* auxColumns;
                if auxSize > 1474560
                    Decimate = ceil(auxSize/1474560);
                else
                    Decimate = 1;
                end
                
                t = app.specData(app.traceInfo.SelectedNode).Data{1}(1:Decimate:end);
                if t(1) == t(end)
                    t(end) = t(1)+seconds(1);
                end
                
                [X, Y] = meshgrid(x, t);
                mesh(app.axes3, X, Y, app.specData(app.traceInfo.SelectedNode).Data{2}(:,1:Decimate:end)')
                view(app.axes3, 0,90);
                
                set(app.axes3, 'YLim' , [t(1); t(end)], 'YTick', [t(1); t(end)], 'YTickLabel', [1; app.specData(app.traceInfo.SelectedNode).Samples]);
            end
            drawnow nocallbacks

            app.axesFlag = 0;
            
        end

        
        function reportInfo = Fcn_ReportGenerator_Aux(app)
            
            reportInfo = struct('Unit', [], 'Task', [], 'CheckBoxes', [], 'Introduction', [], ...
                                'ThreadID', [], 'TimeStamp', [], ...
                                'occType', [], 'occTime', [], 'occFactor', [], 'occFunction', [], 'tSamples', [], 'uSamples', [], ...
                                'C1_autoFlag', [], 'C1_Proeminence', [], 'C1_Width', [], 'C1_Distance', [], 'C1_Function', [], ...
                                'C2_autoFlag', [], 'C2_Proeminence', [], 'C2_Width', [], 'C2_Distance', [], 'C2_OCC', []);
            
            reportInfo.Unit = app.Report_Value1.Value;

            reportInfo.Task = app.Report_Value2.Value;
            
            reportInfo.CheckBoxes = [app.Report_CheckBox1.Value, ...
                                         app.Report_CheckBox2.Value, ...
                                         app.Report_CheckBox3.Value, ...
                                         app.Report_CheckBox4.Value, ...
                                         app.Report_CheckBox5.Value];
            
            if app.Report_CheckBox4.Value
                if isempty(app.htmlSet)
                    app.htmlSet = Fcn_htmlSet('htmlEntityBasicSet.txt');
                end
                
                htmlStr = [];
                for ii = 1:length(app.Report_TextArea.Value)
                    htmlStr = [htmlStr '<p class="Item_Nivel2">' app.Report_TextArea.Value{ii} '</p>'];
                end
                
                for ii = 2:size(app.htmlSet, 1)
                    htmlStr = replace(htmlStr, app.htmlSet.Char{ii}, app.htmlSet.Entity{ii});
                end
                reportInfo.Introduction = htmlStr;
            end
            
            if app.Report_DatePicker1.Value > app.Report_DatePicker2.Value
                uialert(app.MainApp, 'A data de fim não pode ser anterior à de início.', 'Alerta!', 'Icon', 'warning');
                return                    
            end
            
            startDateTime = app.Report_DatePicker1.Value;
            startDateTime.Format = 'dd/MM/yyyy HH:mm:SS';
            startDateTime = startDateTime + hours(app.Report_Spinner1.Value) + minutes(app.Report_Spinner2.Value);
            
            stopDateTime = app.Report_DatePicker2.Value;
            stopDateTime.Format = 'dd/MM/yyyy HH:mm:SS';
            stopDateTime = startDateTime + hours(app.Report_Spinner3.Value) + minutes(app.Report_Spinner4.Value);
            
            reportInfo.TimeStamp = [startDateTime, stopDateTime];
            
            reportInfo.occType     = app.Report_Value3.Value;
            reportInfo.occTime     = app.Report_Value4.Value;
            reportInfo.occFactor   = app.Report_Value5.Value;
            reportInfo.occFunction = app.Report_Value6.Value;
            reportInfo.tSamples    = app.Report_Value7.Value;
            reportInfo.uSamples    = app.Report_Value8.Value;
            
            reportInfo.C1_autoFlag = app.Report_CheckBox6.Value;
            if ~reportInfo.C1_autoFlag
                reportInfo.C1_Function    = app.Report_Value9.Value;
                reportInfo.C1_Proeminence = app.Report_Value10.Value;
                reportInfo.C1_Width       = app.Report_Value11.Value;
                reportInfo.C1_Distance    = app.Report_Value12.Value;
            end
            
            reportInfo.C2_autoFlag = app.Report_CheckBox7.Value;
            if ~reportInfo.C2_autoFlag
                reportInfo.C2_Proeminence = app.Report_Value13.Value;
                reportInfo.C2_Width       = app.Report_Value14.Value;
                reportInfo.C2_Distance    = app.Report_Value15.Value;
                reportInfo.C2_OCC         = app.Report_Value16.Value;
            end
            
        end
        
        
        function Layout_Panel11(app)
            
            if ~isempty(app.SelectedFiles)
                app.Label_11.Enable = 1;
                app.Label_12.Enable = 1; 
                
                app.Tree_11.Enable = 1;
            else
                app.Label_11.Enable = 0;
                app.Label_12.Enable = 0; 
                
                app.Tree_11.Enable = 0;
            end
            
        end
        
        
        function Layout_Panel12(app)
            
            if ~isempty(app.Tree_11.SelectedNodes)
                app.Button_12.Enable = 1;
                app.Label_13.Enable = 1;
                set(app.Grid_12.Children, 'Enable', 1);
            else
                app.Button_12.Enable = 0;
                app.Label_13.Enable = 0;
                set(app.Grid_12.Children, 'Enable', 0);
            end
            
        end
        
        
        function Layout_Panel2(app)
            
            set(app.Grid_21.Children, 'Enable', 1);
            app.Button_24.Enable = 0;
            app.Button_25.Enable = 0;
            app.Button_26.Enable = 0;
            
            app.Warning_21.Visible = 0;
            
            app.Button_21.Value = 0;
            app.Button_22.Value = 0;
            app.Button_23.Value = 0;
            app.Button_26.Value = 0;
            
            Layout_Panel31(app)
            
        end
        
        
        function Layout_Panel31(app)
            
            app.nodesInfo = 0;
            
            app.Panel3_Spec.Visible   = 0;
            app.Panel3_Report.Visible = 0;
            app.Panel3_Info.Visible   = 0;
            
            if app.Button_26.Value
                app.Panel3_Info.Visible = 1;
                app.Image.Layout.Row = 2;
                
                Layout_Panel32(app)
    
            elseif app.Button_21.Value
                app.Panel3_Spec.Visible = 1;
                app.Image.Layout.Row = 2;
                
                app.Button_27.Enable = 0;
                app.Button_28.Enable = 0;
                if ~isempty(app.Tree_21.SelectedNodes)
                    if isnumeric(app.Tree_21.SelectedNodes.NodeData)
                        app.Button_27.Enable = 1;
                        app.Button_28.Enable = 1;
                    end
                end
                
            elseif app.Button_22.Value
                % Pendente implementação da funcionalidade.
                
            elseif app.Button_23.Value
                app.Panel3_Report.Visible = 1;
                app.Image.Layout.Row = 2;
                
                if ~isempty(app.filtData)
                    app.Button_31r.Enable = 1;
                else
                    app.Button_31r.Enable = 0;
                end
                
            else
                app.Image.Layout.Row = 2:3;
                
            end
                
        end

        
        function Layout_Panel32(app)
            
            app.nodesInfo = 1;
            
            if ~isempty(app.Tree_21.SelectedNodes)
                selectedNodes = app.Tree_21.SelectedNodes;
            else
                selectedNodes = app.Tree_22.SelectedNodes;                
            end
            
            app.Info_MetaData1.Value = app.specData(selectedNodes.NodeData).Node;
            app.Info_MetaData2.Value = sprintf('ThreadID %d: %.3f - %.3f MHz',                       ...
                                               app.specData(selectedNodes.NodeData).ThreadID,        ...
                                               1e-6*app.specData(selectedNodes.NodeData).MetaData.F0, ...
                                               1e-6*app.specData(selectedNodes.NodeData).MetaData.F1);
            
            
            % Painel "Informações extraídas de base de dados da Agência"
            if isempty(app.nodesTable)
                app.nodesTable = Fcn_nodeTable('FISF_editedData.csv');
            end
            
            auxIndex = [];
            if isnumeric(selectedNodes.NodeData)
                auxIndex = find(contains(app.nodesTable.Node, app.specData(selectedNodes.NodeData).Node));
                if ~isempty(auxIndex)
                    app.Info_MetaData3.Value = app.nodesTable.Unit(auxIndex(1));
                    app.Info_MetaData4.Value = app.nodesTable.City(auxIndex(1));
                    app.Info_MetaData5.Value = app.nodesTable.Latitude(auxIndex(1));
                    app.Info_MetaData6.Value = app.nodesTable.Longitude(auxIndex(1));
                    
                    app.Info_Grid1.RowHeight = {22, 22, 22, 22, 22, 116, 22, '100x', 22};
                else
                    app.Info_Grid1.RowHeight = {22, 22, 22, 22, 0, 0, 22, '100x', 22};
                end
            end
            
            
            % Painel "Informações a editar"
            if ~app.specData(selectedNodes.NodeData).gpsFlag
                app.Info_Latitude.Enable  = 0;
                app.Info_Longitude.Enable = 0;
                
                auxStr = strsplit(app.specData(selectedNodes.NodeData).gpsString);
                
                app.Info_Latitude.Value  = str2double(auxStr{1});
                app.Info_Longitude.Value = str2double(auxStr{2});
            else
                app.Info_Latitude.Enable  = 1;
                app.Info_Longitude.Enable = 1;
                
                if ~isempty(auxIndex)
                    app.Info_Latitude.Value  = app.Info_MetaData5.Value;
                    app.Info_Longitude.Value = app.Info_MetaData6.Value;
                else
                    app.Info_Latitude.Value  = 0;
                    app.Info_Longitude.Value = 0;
                end
            end
            
            if isempty(app.specData(selectedNodes.NodeData).occRelated)
                app.Info_OCC.Enable = 0;
                app.Info_OCC.Items  = {'Ocupação a ser aferida'};
                
            else
                app.Info_OCC.Enable = 1;
                
                switch app.specData(selectedNodes.NodeData).MetaData.DTYPE
                    case 0; auxStr = 'dBm';
                    case 1; auxStr = 'dBµV/m';
                end
                
                app.Info_OCC.Items{1} = 'Ocupação a ser aferida';
                for ii = 1:length(app.specData(selectedNodes.NodeData).occRelated)
                    app.Info_OCC.Items{ii+1} = sprintf('Threshold: %d %s (ThreadID %d)', app.specData(app.specData(selectedNodes.NodeData).occRelated(ii)).Threshold, auxStr, app.specData(app.specData(selectedNodes.NodeData).occRelated(ii)).ThreadID);
                end
                
                if ~app.specData(selectedNodes.NodeData).occFlag
                    app.Info_OCC.Value = app.Info_OCC.Items{1};
                else
                    auxIndex = find(app.specData(selectedNodes.NodeData).occRelated == app.specData(selectedNodes.NodeData).occFlag);
                    app.Info_OCC.Value = app.Info_OCC.Items{auxIndex+1};
                end
            end
            % Botão
            app.Info_Button.Enable = 1;
            
        end
                
        
        function Fcn_Traces(app)

            % Tendência central.
            app.RadioButton_04.Enable = 0;
            app.RadioButton_05.Enable = 0;
            app.RadioButton_03.Enable = 0;
            
            app.FatordeintegraoLabel.Enable = 0;
            app.Value_01.Enable = 0;
            
            if app.CheckBox_01.Value
                if app.RadioButton_01.Value
                    app.RadioButton_04.Enable = 1;
                    app.RadioButton_05.Enable = 1;
                    app.RadioButton_03.Enable = 1;
                else
                    app.RadioButton_05.Value  = 1;
                    app.RadioButton_05.Enable = 1;
                    
                    app.FatordeintegraoLabel.Enable = 1;
                    app.Value_01.Enable = 1;
                end
                
                switch app.RadioButtonGroup_02.SelectedObject.Text
                    case 'Moda'
                        app.traceInfo.Mode(2) = 2;
                    case 'Mediana'
                        app.traceInfo.Mode(2) = 3;
                    case 'Média (vídeo)'
                        app.traceInfo.Mode(2) = 4;
                end
            else
                app.traceInfo.Mode(2) = 0;
            end
            
            % MinHold
            if app.CheckBox_02.Value
                app.traceInfo.Mode(1) = 1;
            else
                app.traceInfo.Mode(1) = 0;
            end

            % MaxHold
            if app.CheckBox_03.Value
                app.traceInfo.Mode(3) = 5;
            else
                app.traceInfo.Mode(3) = 0;
            end
            
        end
        
        function Layout_Plot(app)
            
            if app.CheckBox_04.Value && app.CheckBox_05.Value
                app.traceInfo.plotLayout = 3;
            elseif app.CheckBox_05.Value
                app.traceInfo.plotLayout = 2;
            elseif app.CheckBox_04.Value
                app.traceInfo.plotLayout = 1;
            else
                app.traceInfo.plotLayout = 0;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.Image.Visible = 1;
            app.Warning_21.Visible = 0;
            
            app.Panel3_Spec.Visible   = 0;
            app.Panel3_Report.Visible = 0;
            app.Panel3_Info.Visible   = 0;
            
            % Figure creation.
            app.fig   = figure('Color', 'none', ...
                               'MenuBar', 'none', 'ToolBar', 'none', 'Visible', 'off', ...
                               'Name', '', 'NumberTitle', 'off',                       ...
                               'CloseRequestFcn', "set(gcf, 'Visible', 'off')");
            
            mainMonitor = get(0, 'MonitorPositions');
            mainMonitor = mainMonitor(mainMonitor(:,1) == 1,:);
            
            x0 = floor((mainMonitor(3) - app.fig.Position(3))/2);
            y0 = floor((mainMonitor(4) - app.fig.Position(4))/2);
            
            app.fig.Position = [x0, y0, 853, 480];        

            % Axes and objects creation.
            app.axes1 = axes(app.fig, 'Box', 'on', 'Color', 'none', 'FontName', 'Calibri', 'FontSize', 9, 'FontSmoothing', 'off', ...
                                      'XColor', [0.8,0.8,0.8], 'XGrid', 'on', 'XMinorGrid', 'on',  ...
                                      'YColor', [0.8,0.8,0.8], 'YGrid', 'on', 'YMinorGrid', 'on',  ...
                                      'GridAlpha'     , .25, 'GridColor'     , [0.94,0.94,0.94], ...
                                      'MinorGridAlpha', .2 , 'MinorGridColor', [0.94,0.94,0.94], ...
                                      'Unit', 'normalized', 'InnerPosition', [.08 .1 .85 .82],   ...
                                      'TitleHorizontalAlignment', 'right');
            
            app.axes2 = axes(app.fig, 'Box', 'on', 'Color', 'none', 'FontName', 'Calibri', 'FontSize', 9, 'FontSmoothing', 'off', ...
                                      'XColor', [0.8,0.8,0.8], 'XGrid', 'on', 'XMinorGrid', 'on',  ...
                                      'YColor', [0.8,0.8,0.8], 'YGrid', 'on', 'YMinorGrid', 'on',  ...
                                      'GridAlpha', .25, 'GridColor', [0.94,0.94,0.94],  ...
                                      'MinorGridAlpha', .2 , 'MinorGridColor', [0.94,0.94,0.94], ...
                                      'Unit', 'normalized', 'InnerPosition', [0 0 0 0], ...
                                      'Layer', 'top');
            
            app.axes3 = axes(app.fig, 'Box', 'on', 'Color', 'none', 'FontName', 'Calibri', 'FontSize', 9, 'FontSmoothing', 'off', ...
                                      'XColor', [0.8,0.8,0.8], ...
                                      'YColor', [0.8,0.8,0.8], ...
                                      'Unit', 'normalized', 'InnerPosition', [0 0 0 0]);
            
            app.axes1.XAxis.Exponent = 6;
            app.axes2.XAxis.Exponent = 6;
            app.axes3.XAxis.Exponent = 6;
            
            app.cBar1 = colorbar('Location', 'manual', 'Units', 'normalized', 'Position', [0.935, 0.1, 0.01, 0.1], ...
                                 'AxisLocation', 'out', 'FontSize', 9, 'Color', [0.8,0.8,0.8]);
            
            app.line1 = annotation(app.fig, 'line', 'X', [.08 .93], 'Y', [.1 .1], 'Position', [0 0 0 0], ...
                                                    'LineWidth', 1, 'Color', 'red');            
            
            hold(app.axes1, 'on')
            hold(app.axes2, 'on')
            hold(app.axes3, 'on')
            
        end

        % Button pushed function: Button_11
        function Button_OpenFile(app, event)
            
            [Files, Path] = uigetfile('*.bin', 'Select One or More Files', 'MultiSelect', 'on');
            
            if ~isnumeric(Files)
                if ~iscell(Files)
                    Files = {Files};
                end
                
                repeteadFiles = {};
                
                if isempty(app.SelectedFiles)
                    app.SelectedFiles = Files;
                    app.FilesPath     = repmat({Path}, 1, length(Files));
                else
                    for ii = length(Files):-1:1
                        if any(cellfun(@(s) ~isempty(strfind(Files{ii}, s)), app.SelectedFiles))
                            repeteadFiles = [repeteadFiles; Files{ii}];
                            Files(ii) = [];
                        end
                    end
                                        
                    app.SelectedFiles(end+1:end+length(Files)) = Files;
                    app.FilesPath(end+1:end+length(Files))     = repmat({Path}, 1, length(Files));                    
                end
                                
                Fcn_MetaDataReader(app)
                
                % Alert window
                if ~isempty(repeteadFiles)
                    auxStr = 'Os metadados do(s) arquivo(s) indicado(s) a seguir já tinham sido lidos.';
                    for ii = 1:length(repeteadFiles)
                        auxStr = [auxStr sprintf('\n- %s', repeteadFiles{ii})];                       
                    end
                    uialert(app.MainApp, auxStr, 'Alerta!', 'Icon', 'warning');
                end
                
                Fcn_Tree1(app)
            end
            
            Layout_Panel11(app)
            Layout_Panel12(app)
            
        end

        % Button pushed function: Button_12
        function Button_Delete(app, event)
            
            selectedNodes = app.Tree_11.SelectedNodes;
            
            app.SelectedFiles(selectedNodes.NodeData) = [];
            app.FilesPath(selectedNodes.NodeData)     = [];
            app.metaData(selectedNodes.NodeData)      = [];
            
            Fcn_Tree1(app)
            
            Layout_Panel11(app)
            Layout_Panel12(app)
                        
        end

        % Button pushed function: Button_15
        function SpecDataReadCallback(app, event)
            
            app.dialogWin = uiprogressdlg(app.MainApp, 'Indeterminate', 'on');
            
            if ~isempty(app.filtData)
                app.filtData = [];
                delete(app.Tree_22.Children);
            end
            
            app.traceInfo.SelectedNode = 1;
            app.timeIndex = 0;

            Fcn_SpecDataReader(app)
            Fcn_Tree2(app)
            
            Layout_Panel2(app)
            
            delete(app.dialogWin)
            
        end

        % Value changed function: metaData_13
        function SelectThreaID(app, event)
            
            value = str2double(app.metaData_13.Value);
            selectedNodes = app.Tree_11.SelectedNodes;
            
            Index = find([app.metaData(selectedNodes.NodeData).Data.ThreadID] == value);
            
            app.metaData_14.Value = sprintf('%.3f - %.3f MHz', app.metaData(selectedNodes.NodeData).Data(Index).MetaData.F0.*1e-6, app.metaData(selectedNodes.NodeData).Data(Index).MetaData.F1.*1e-6);
            app.metaData_15.Value = app.metaData(selectedNodes.NodeData).Data(Index).Description;
            
        end

        % Selection changed function: ButtonGroup31
        function ButtonGroup31SelectionChanged(app, event)
            
            if app.Button31.Value
                app.Button33.Enable = 0;
            else
                app.Button33.Enable = 1;
            end
            
        end

        % Value changed function: Pk_Value1
        function Pk_Value1ValueChanged(app, event)
            
            value = app.Pk_Value1.Value;
            
            app.Pk_Label2.Text = 'Marker 2:';
            app.Pk_Value8.Visible = 0;
            app.Pk_Value8.Limits = [2, Inf];
            
            if value > 1
                app.Pk_Label2.Enable = 1;
                
                app.Pk_Value9.Enable = 1;
                app.Pk_Value10.Enable = 1;
                app.Pk_Unit8.Enable  = 1;
                app.Pk_Unit9.Enable  = 1;
                
                if value > 2
                    app.Pk_Label2.Text  = 'Others markers:';
                    
                    app.Pk_Value8.Visible = 1;
                    app.Pk_Value8.Limits = [2, value];
                end
                
            else
                app.Pk_Label2.Enable = 0;
                
                app.Pk_Value9.Enable = 0;
                app.Pk_Value10.Enable = 0;
                app.Pk_Unit8.Enable  = 0;
                app.Pk_Unit9.Enable  = 0;
            end
            
        end

        % Selection changed function: Tree_11
        function Tree1_SelectedNodes(app, event)
            
            selectedNodes = app.Tree_11.SelectedNodes;
            
            app.metaData_11.Value = app.metaData(selectedNodes.NodeData).Data(1).FileVersion;
            app.metaData_12.Value = app.metaData(selectedNodes.NodeData).Data(1).Node;
            
            app.metaData_13.Items = {num2str(app.metaData(selectedNodes.NodeData).Data(1).ThreadID)};
            
            value = length(app.metaData(selectedNodes.NodeData).Data);
            if value > 1
                for ii = 2:value
                    app.metaData_13.Items = [app.metaData_13.Items, num2str(app.metaData(selectedNodes.NodeData).Data(ii).ThreadID)];
                end
            end
            app.metaData_13.Value = app.metaData_13.Items{1};
            
            app.metaData_14.Value = sprintf('%.3f - %.3f MHz', app.metaData(selectedNodes.NodeData).Data(1).MetaData.F0.*1e-6, app.metaData(selectedNodes.NodeData).Data(1).MetaData.F1.*1e-6);
            app.metaData_15.Value = app.metaData(selectedNodes.NodeData).Data(1).Description;
                        
            Layout_Panel12(app)
            
        end

        % Selection changed function: Tree_21
        function Tree2_SelectedNodes(app, event)
            
            app.Tree_22.SelectedNodes = [];
            
            if app.plotFlag
                if isnumeric(app.Tree_21.SelectedNodes.NodeData)
                    app.axesFlag = 1;
                end
                
            else
                app.Button_24.Enable = 1;
                app.Button_25.Enable = 0;
                
                app.Button_27.Enable = 0;
                app.Button_28.Enable = 0;
                if isnumeric(app.Tree_21.SelectedNodes.NodeData)
                    app.Button_26.Enable = 1;
                    if app.nodesInfo
                        Layout_Panel32(app)
                    end
                    
                    if app.Button_21.Value
                        app.Button_27.Enable = 1;
                        app.Button_28.Enable = 1;
                    end
                    
                else
                    app.Button_26.Value     = 0;
                    app.Button_26.Enable    = 0;
                    app.Panel3_Info.Visible = 0;
                end
            end
            
        end

        % Button pushed function: Button_34
        function Button_34Pushed(app, event)
            
            auxStr = sprintf('Análise de sinais\nv.0.1\n\n2020-2021');            
            uialert(app.MainApp, auxStr, 'Sobre', 'Icon', 'info');
            
        end

        % Value changed function: Button_21
        function Button_21ValueChanged(app, event)
            
            if app.Button_21.Value
                app.Button_22.Value = 0;
                app.Button_23.Value = 0;
                app.Button_26.Value = 0;                
            end
            
            Layout_Panel31(app)
            
        end

        % Value changed function: Button_22
        function Button_22ValueChanged(app, event)

            if app.Button_22.Value
                app.Button_21.Value = 0;
                app.Button_23.Value = 0;
                app.Button_26.Value = 0;                
            end
            
            Layout_Panel31(app)
            
        end

        % Value changed function: Button_23
        function Button_23ValueChanged(app, event)
            
            if app.Button_23.Value
                app.Button_21.Value = 0;
                app.Button_22.Value = 0;
                app.Button_26.Value = 0;                
            end
            
            Layout_Panel31(app)
            
        end

        % Button pushed function: Button_24
        function Button_24Pushed(app, event)
            
            selectedNodes = app.Tree_21.SelectedNodes;
            
            if isnumeric(selectedNodes.NodeData)
                auxIndex = selectedNodes.NodeData;
            else
                auxIndex = [];
                for ii = 1:length(app.specData)
                    if strcmp(app.specData(ii).Node, selectedNodes.NodeData)
                        auxIndex = [auxIndex, ii];
                    end
                end
            end
            
            for jj = 1:length(auxIndex)
                if isempty(app.specData(auxIndex(jj)).gpsString)
                    auxStr = sprintf('Necessária a edição de informações de coordenadas geográficas de fluxo(s) de dado(s).');            
                    uialert(app.MainApp, auxStr, 'Alerta!', 'Icon', 'error');
                    return
                end
                
                if isempty(find(app.filtData == auxIndex(jj), 1))
                    app.filtData = sort([app.filtData; auxIndex(jj)]);
                end
            end
            
            Fcn_Tree3(app)
            
            if ~isempty(app.filtData)
                app.Button_31r.Enable = 1;
            else
                app.Button_31r.Enable = 0;
            end
    
        end

        % Button pushed function: Button_25
        function Button_25Pushed(app, event)
            
            selectedNodes = app.Tree_22.SelectedNodes;
            
            if isnumeric(selectedNodes.NodeData)
                app.filtData(find(app.filtData == selectedNodes.NodeData, 1)) = [];
                
            else
                auxIndex1 = [];
                for ii = 1:length(app.specData)
                    if strcmp(app.specData(ii).Node, selectedNodes.NodeData)
                        auxIndex1 = [auxIndex1, ii];
                    end
                end
                
                for jj = 1:length(auxIndex1)
                    auxIndex2 = find(app.filtData == auxIndex1(jj), 1);
                    
                    if ~isempty(auxIndex2)
                        app.filtData(auxIndex2) = [];
                    end
                end
            end
            
            Fcn_Tree3(app)
            
            if isempty(app.filtData)
                app.Button_25.Enable = 0;
                app.Button_31r.Enable = 0;
            end
            
        end

        % Value changed function: Button_26
        function Button_26Pushed(app, event)
            
            Layout_Panel31(app)
            
        end

        % Button pushed function: Button_28
        function Button_28Pushed(app, event)
            
            if app.reloadFlag
                app.Button_28.Icon = 'notreloadIcon.png';
                app.reloadFlag = 0;
            else
                app.Button_28.Icon = 'reloadIcon.png';
                app.reloadFlag = 1;
            end
            
        end

        % Button pushed function: Button_29
        function Button_29Pushed2(app, event)
            
            if app.Grid_21.RowHeight{6}
                app.Grid_21.RowHeight = {28, 22, 44, '1x', 15, 0, 0, 22};
            else
                app.Grid_21.RowHeight = {28, 22, 44, '1x', 15, 22, 200, 22};
            end
            
        end

        % Button pushed function: Button_31r
        function Button_31rPushed(app, event)
            
            % Geração de relatório.
            d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on');
            d.Message = sprintf(['Em andamento a elaboração do relatório de monitoração, o que inclui diversas manipulações ' ...
                                 'nos dados, como, por exemplo, a busca de emissões, a comparação com a base de dados da Agência ' ...
                                 'e a geração de plots.']);
            
            reportInfo = Fcn_ReportGenerator_Aux(app);
            
            filteredRows = sort([app.filtData; unique([app.specData(app.filtData).occFlag])']);
            
            auxValue1 = length([app.specData(app.filtData).occFlag]);
            if auxValue1
                auxValue2 = zeros(auxValue1, 2);
                
                jj = 0;
                for ii = 1:length(filteredRows)
                    if ~isempty(app.specData(filteredRows(ii)).occFlag)
                        jj=jj+1;
                        auxValue2(jj,:) = [filteredRows(ii), app.specData(filteredRows(ii)).occFlag];
                        app.specData(filteredRows(ii)).occFlag = find(filteredRows == auxValue2(jj,2));
                    end
                end
            end
            
            TimeStampFilter(1) = {app.Report_DatePicker1.Value + hours(app.Report_Spinner1.Value) + minutes(app.Report_Spinner2.Value)};
            TimeStampFilter(2) = {app.Report_DatePicker2.Value + hours(app.Report_Spinner3.Value) + minutes(app.Report_Spinner4.Value)};
            
            Fcn_ReportGenerator(reportInfo, app.specData(filteredRows), TimeStampFilter)
            
            if auxValue1
                for jj = 1:size(auxValue2, 1)
                    app.specData(auxValue2(jj,1)).occFlag = auxValue2(jj,2);
                end
            end
            
        end

        % Button pushed function: Button_35
        function Button_35Pushed(app, event)
            
            if app.Image.Layout.Row == 2
                app.Image.Layout.Row = 2:3;
            else
                app.Image.Layout.Row = 2;
            end
            
        end

        % Button pushed function: Button_32
        function globalVariables_Callback(app, event)
            
            global metaData1
            global specData1
            global filtData1
            global traceFlag1
            
            metaData1   = app.metaData;
            specData1   = app.specData;
            filtData1   = app.filtData;
            traceFlag1  = app.traceInfo;
            
            auxStr = sprintf(['Variáveis "specData1" e "filtData1" definidas como globais, sendo possível a sua importação no Work Space do Matlab ' ...
                              'por meio dos comandos:\n• global specData1\n• global filtData1']);            
            uialert(app.MainApp, auxStr, 'Alerta!', 'Icon', 'warning');
            
        end

        % Value changed function: Report_CheckBox4
        function Report_CheckBox4ValueChanged(app, event)
            
            if app.Report_CheckBox4.Value
                app.Report_TextArea.Enable = 1;
            else
                if ~app.Report_CheckBox2.Value && ~app.Report_CheckBox3.Value
                    app.Report_CheckBox4.Value = 1;
                else
                    app.Report_TextArea.Enable = 0;
                end
            end
            
        end

        % Button pushed function: Info_Button
        function Info_ButtonPushed(app, event)
            
            if ~isempty(app.Tree_21.SelectedNodes)
                selectedNodes = app.Tree_21.SelectedNodes;
            elseif ~isempty(app.Tree_22.SelectedNodes)
                selectedNodes = app.Tree_22.SelectedNodes;
            end
            
            auxArray = app.filtData;
            
            if app.specData(selectedNodes.NodeData).gpsFlag
                app.specData(selectedNodes.NodeData).gpsString = sprintf('%.6f, %.6f', app.Info_Latitude.Value, app.Info_Longitude.Value);            
                app.specData(selectedNodes.NodeData).gpsFlag   = 2;
            end
            
            if app.Info_CheckBox.Value
                for ii = 1:length(app.specData)
                    if ii == selectedNodes.NodeData
                        continue
                    end
                    
                    if strcmp(app.specData(selectedNodes.NodeData).Node, app.specData(ii).Node) && app.specData(ii).gpsFlag
                        app.specData(ii).gpsString = sprintf('%.6f, %.6f', app.Info_Latitude.Value, app.Info_Longitude.Value);            
                        app.specData(ii).gpsFlag   = 2;
                    end
                end
            end
            
            if ~isempty(app.specData(selectedNodes.NodeData).occRelated)
                if strcmp(app.Info_OCC.Items{1}, app.Info_OCC.Value)
                    app.specData(selectedNodes.NodeData).occFlag = 0;
                else
                    auxIndex = find(strcmp(app.Info_OCC.Items, app.Info_OCC.Value))-1;
                    app.specData(selectedNodes.NodeData).occFlag = app.specData(selectedNodes.NodeData).occRelated(auxIndex);
                end
            end
            
            Fcn_Tree2(app)
            if ~isempty(app.filtData)
                Fcn_Tree3(app)
            end
            
            app.Button_26.Value = 0;
            Layout_Panel31(app)
            
        end

        % Value changed function: Report_CheckBox6
        function Report_CheckBox6ValueChanged(app, event)
            
            value = app.Report_CheckBox6.Value;
            
            if value
                app.Report_Value9.Enable = 0;
                app.Report_Value10.Enable = 0;
                app.Report_Value11.Enable = 0;
                app.Report_Value12.Enable = 0;
                app.Report_Unit10.Enable = 0;
                app.Report_Unit11.Enable = 0;
                app.Report_Unit12.Enable = 0;
            else
                app.Report_Value9.Enable = 1;
                app.Report_Value10.Enable = 1;
                app.Report_Value11.Enable = 1;
                app.Report_Value12.Enable = 1;
                app.Report_Unit10.Enable = 1;
                app.Report_Unit11.Enable = 1;
                app.Report_Unit12.Enable = 1;
            end
            
        end

        % Value changed function: Report_CheckBox7
        function Report_CheckBox7ValueChanged(app, event)
            
            value = app.Report_CheckBox7.Value;
            
            if value
                app.Report_Value13.Enable = 0;
                app.Report_Value14.Enable = 0;
                app.Report_Value15.Enable = 0;
                app.Report_Value16.Enable = 0;
                app.Report_Unit13.Enable = 0;
                app.Report_Unit14.Enable = 0;
                app.Report_Unit15.Enable = 0;
                app.Report_Unit16.Enable = 0;
            else
                app.Report_Value13.Enable = 1;
                app.Report_Value14.Enable = 1;
                app.Report_Value15.Enable = 1;
                app.Report_Value16.Enable = 1;
                app.Report_Unit13.Enable = 1;
                app.Report_Unit14.Enable = 1;
                app.Report_Unit15.Enable = 1;
                app.Report_Unit16.Enable = 1;
            end
            
        end

        % Value changed function: Report_CheckBox2
        function Report_CheckBox2ValueChanged(app, event)
            
            value = app.Report_CheckBox2.Value;
            
            if ~value && ~app.Report_CheckBox3.Value && ~app.Report_CheckBox4.Value
                app.Report_CheckBox2.Value = 1;  
            end
            
        end

        % Value changed function: Report_CheckBox3
        function Report_CheckBox3ValueChanged(app, event)

            value = app.Report_CheckBox3.Value;
            
            if ~value && ~app.Report_CheckBox2.Value && ~app.Report_CheckBox4.Value
                app.Report_CheckBox3.Value = 1;  
            end
            
        end

        % Selection changed function: Tree_22
        function Tree_22SelectionChanged(app, event)
            
            app.Tree_21.SelectedNodes = [];
            
            app.Button_24.Enable = 0;
            app.Button_25.Enable = 1;
            
            app.Button_27.Enable = 0;
            app.Button_28.Enable = 0;
            
            if isnumeric(app.Tree_22.SelectedNodes.NodeData)
                app.Button_26.Enable = 1;
                if app.nodesInfo
                    Layout_Panel31(app)
                end
            else
                app.Button_26.Value     = 0;
                app.Button_26.Enable    = 0;
                app.Panel3_Info.Visible = 0;
            end
            
        end

        % Button pushed function: Button_27
        function Layout_Playback(app, event)

            % Playback.
            if ~app.plotFlag
                app.plotFlag = 1;
                app.Slider31.Enable = 0;
                
                app.Button_27.Icon = 'Media_Pause.png';
                
                app.Button_11.Enable = 0;
                app.Button_15.Enable = 0;
                
                set(app.Grid_21.Children, 'Enable', 0);
                app.Tree_21.Enable = 1;
                drawnow nocallbacks
                
                Fcn_Plot(app)
            
            else
                app.plotFlag = 0;
                app.Slider31.Enable = 1;
                
                app.Button_27.Icon = 'Media_Play.png';
                
                app.Button_11.Enable = 1;
                if ~isempty(app.SelectedFiles)
                    app.Button_15.Enable = 1;
                end
                
                set(app.Grid_21.Children, 'Enable', 1);
                
                if ~isnumeric(app.Tree_21.SelectedNodes.NodeData)
                    app.Button_24.Enable = 0;
                    app.Button_26.Enable = 0;
                end
                app.Button_25.Enable = 0;
                
                drawnow nocallbacks
            end
            
        end

        % Close request function: MainApp
        function MainAppCloseRequest(app, event)
            
            if ~isempty(app.fig)
                delete(app.fig)
            end
            delete(app)
            
        end

        % Value changed function: BarrademenuCheckBox
        function BarrademenuCheckBoxValueChanged(app, event)
            
            if ~isempty(app.fig)
                if app.BarrademenuCheckBox.Value
                    set(app.fig, 'MenuBar', 'figure')
                else
                    set(app.fig, 'MenuBar', 'none')
                end
            end
            
        end

        % Value changed function: BarradeferramentasCheckBox
        function BarradeferramentasCheckBoxValueChanged(app, event)
            
            if ~isempty(app.fig)
                if app.BarradeferramentasCheckBox.Value
                    set(app.fig, 'ToolBar', 'figure')
                else
                    set(app.fig, 'ToolBar', 'none')
                end
            end
            
        end

        % Value changed function: CheckBox_04, CheckBox_05
        function CheckBox32_2ValueChanged(app, event)
            
            Layout_Plot(app)

            Report_Value6_2ValueChanged(app)
            
        end

        % Value changing function: Slider31
        function Slider31ValueChanging(app, event)
            
            app.timeIndex = round(event.Value/100 * app.specData(app.traceInfo.SelectedNode).Samples);
            
            if app.timeIndex < 1
                app.timeIndex = 1;
            elseif app.timeIndex > app.specData(app.traceInfo.SelectedNode).Samples
                app.timeIndex = app.specData(app.traceInfo.SelectedNode).Samples;
            end
            
            app.titleStr.String = datestr(app.specData(app.traceInfo.SelectedNode).Data{1}(app.timeIndex), 'dd/mm/yyyy HH:MM:SS');
            app.axes1.Children(end).YData = app.specData(app.traceInfo.SelectedNode).Data{2}(:, app.timeIndex)';
            
            if app.traceInfo.plotLayout == 2
                app.line1.Position(2) = .1+.4*(app.timeIndex/app.specData(app.traceInfo.SelectedNode).Samples);
            elseif app.traceInfo.plotLayout == 3
                app.line1.Position(2) = .1+.3*(app.timeIndex/app.specData(app.traceInfo.SelectedNode).Samples);
            end
            drawnow
            
        end

        % Button pushed function: Button_31
        function winLayout_Callback(app, event)
            
            if app.Grid1.ColumnWidth{1} == 310
                app.Grid1.ColumnWidth = {0,0,180,'1x',20,310};
            else
                app.Grid1.ColumnWidth = {310,20,180,'1x',20,310};
            end
            
        end

        % Selection changed function: RadioButtonGroup_01
        function integrationType_Callback(app, event)
            
            app.CheckBox_01.Value = 0;
            app.CheckBox_02.Value = 0;
            app.CheckBox_03.Value = 0;
            
            app.RadioButton_04.Enable = 0;
            app.RadioButton_05.Enable = 0;
            
            app.RadioButton_03.Enable = 0;
            app.Value_01.Enable = 0;
            
            Fcn_Traces(app)
            
            Report_Value6_2ValueChanged(app)
            
        end

        % Callback function: CheckBox_01, CheckBox_02, CheckBox_03, 
        % RadioButtonGroup_02, Value_01
        function axesFlag_Callback(app, event)
            
            Fcn_Traces(app)
            
            Report_Value6_2ValueChanged(app)
            
        end

        % Value changed function: THR_Type
        function THR_TypeValueChanged(app, event)
            
            switch app.THR_Type.Value
                case 'Linear'
                    app.ValorLabel.Enable = 1;
                    app.FatordeintegraoLabel_2.Enable = 1;
                    app.THR_Unit.Enable = 1;
                    
                    app.THR_Value.Enable = 1;
                    app.Value31_3.Enable = 1;
                    
                    app.FunoestatsticaDropDownLabel_3.Enable = 0;
                    app.OffSetLabel.Enable = 0;
                    app.AmostrasteisLabel_2.Enable = 0;
                    app.NveldeconfianaLabel.Enable = 0;
                    app.Label_29.Enable = 0;
                    
                    app.Report_Value6_2.Enable = 0;
                    app.Report_Value5_3.Enable = 0;
                    app.Report_Value8_2.Enable = 0;
                    app.Report_Value6_3.Enable = 0;
                    
                case 'Estimativa do piso de ruído acrescida de OffSet'
                    app.ValorLabel.Enable = 0;
                    app.THR_Unit.Enable = 0;
                    app.FatordeintegraoLabel_2.Enable = 0;
                    
                    app.THR_Value.Enable = 0;
                    app.Value31_3.Enable = 0;
                    
                    app.FunoestatsticaDropDownLabel_3.Enable = 1; 
                    app.OffSetLabel.Enable = 1;
                    app.AmostrasteisLabel_2.Enable = 1;
                    app.NveldeconfianaLabel.Enable = 1;
                    app.Label_29.Enable = 1;
                    
                    app.Report_Value6_2.Enable = 1;
                    app.Report_Value5_3.Enable = 1;
                    app.Report_Value8_2.Enable = 1;
                    app.Report_Value6_3.Enable = 1;
                    
            end
            
            Report_Value6_2ValueChanged(app)
            
        end

        % Value changed function: Report_Value5_3, Report_Value6_2, 
        % Report_Value6_3, Report_Value8_2, THR_Value, Value31_3
        function Report_Value6_2ValueChanged(app, event)

            if app.plotFlag
                app.axesFlag = 1;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainApp = uifigure('Visible', 'off');
            app.MainApp.Name = 'ANÁLISE';
            app.MainApp.Icon = '3A.png';
            app.MainApp.CloseRequestFcn = createCallbackFcn(app, @MainAppCloseRequest, true);
            app.Grid1 = uigridlayout(app.MainApp);
            app.Panel2 = uipanel(app.Grid1);
            app.Panel2.Title = 'TAREFAS';
            app.Grid_21 = uigridlayout(app.Panel2);
            app.Tree_22 = uitree(app.Grid_21);
            app.Tree_22.SelectionChangedFcn = createCallbackFcn(app, @Tree_22SelectionChanged, true);
            app.Tree_22.Enable = 'off';
            app.Tree_21 = uitree(app.Grid_21);
            app.Tree_21.SelectionChangedFcn = createCallbackFcn(app, @Tree2_SelectedNodes, true);
            app.Tree_21.Enable = 'off';
            app.Label_23 = uilabel(app.Grid_21);
            app.Label_23.Enable = 'off';
            app.Label_23.Text = 'Fluxo(s) de dados a processar';
            app.Label_22 = uilabel(app.Grid_21);
            app.Label_22.Enable = 'off';
            app.Label_22.Text = '0 alerta(s)';
            app.Label_21 = uilabel(app.Grid_21);
            app.Label_21.Enable = 'off';
            app.Label_21.Text = 'Fluxo(s) de dados';
            app.Warning_21 = uiimage(app.Grid_21);
            app.Warning_21.ImageSource = 'treeAlarm2.png';
            app.Button_29 = uibutton(app.Grid_21, 'push');
            app.Button_29.ButtonPushedFcn = createCallbackFcn(app, @Button_29Pushed2, true);
            app.Button_29.Icon = 'InstrumentScan4.png';
            app.Button_29.Enable = 'off';
            app.Button_29.Text = '';
            app.Button_26 = uibutton(app.Grid_21, 'state');
            app.Button_26.ValueChangedFcn = createCallbackFcn(app, @Button_26Pushed, true);
            app.Button_26.Enable = 'off';
            app.Button_26.Tooltip = {''};
            app.Button_26.Icon = 'Imagem2.png';
            app.Button_26.Text = '';
            app.Button_25 = uibutton(app.Grid_21, 'push');
            app.Button_25.ButtonPushedFcn = createCallbackFcn(app, @Button_25Pushed, true);
            app.Button_25.Icon = 'delete3.png';
            app.Button_25.Enable = 'off';
            app.Button_25.Text = '';
            app.Button_24 = uibutton(app.Grid_21, 'push');
            app.Button_24.ButtonPushedFcn = createCallbackFcn(app, @Button_24Pushed, true);
            app.Button_24.Icon = 'adicionar2.png';
            app.Button_24.Enable = 'off';
            app.Button_24.Text = '';
            app.Button_23 = uibutton(app.Grid_21, 'state');
            app.Button_23.ValueChangedFcn = createCallbackFcn(app, @Button_23ValueChanged, true);
            app.Button_23.Enable = 'off';
            app.Button_23.Tooltip = {'Geração de relatório.'};
            app.Button_23.Icon = 'ReportIcon.png';
            app.Button_23.Text = '';
            app.Button_22 = uibutton(app.Grid_21, 'state');
            app.Button_22.ValueChangedFcn = createCallbackFcn(app, @Button_22ValueChanged, true);
            app.Button_22.Enable = 'off';
            app.Button_22.Tooltip = {'Análise de canal no domínio do tempo.'};
            app.Button_22.Icon = 'RF5.png';
            app.Button_22.Text = '';
            app.Button_21 = uibutton(app.Grid_21, 'state');
            app.Button_21.ValueChangedFcn = createCallbackFcn(app, @Button_21ValueChanged, true);
            app.Button_21.Enable = 'off';
            app.Button_21.Tooltip = {'Análise de faixa de frequência.'};
            app.Button_21.Icon = 'RF2.png';
            app.Button_21.Text = '';
            app.Panel1 = uipanel(app.Grid1);
            app.Panel1.Title = 'INFORMAÇÕES GERAIS';
            app.Grid_11 = uigridlayout(app.Panel1);
            app.Tree_11 = uitree(app.Grid_11);
            app.Tree_11.SelectionChangedFcn = createCallbackFcn(app, @Tree1_SelectedNodes, true);
            app.Tree_11.Enable = 'off';
            app.Button_15 = uibutton(app.Grid_11, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @SpecDataReadCallback, true);
            app.Button_15.Icon = 'Connect.png';
            app.Button_15.Enable = 'off';
            app.Button_15.Text = '';
            app.Button_12 = uibutton(app.Grid_11, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_Delete, true);
            app.Button_12.Icon = 'delete3.png';
            app.Button_12.Enable = 'off';
            app.Button_12.Text = '';
            app.Button_11 = uibutton(app.Grid_11, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_OpenFile, true);
            app.Button_11.Icon = 'ImportIcon.png';
            app.Button_11.Text = '';
            app.Label_13 = uilabel(app.Grid_11);
            app.Label_13.Enable = 'off';
            app.Label_13.Text = 'Metadados';
            app.Label_12 = uilabel(app.Grid_11);
            app.Label_12.Enable = 'off';
            app.Label_12.Text = '0 arquivo(s) selecionado(s)';
            app.Label_11 = uilabel(app.Grid_11);
            app.Label_11.Enable = 'off';
            app.Label_11.Text = 'Arquivo(s)';
            app.Grid_12 = uigridlayout(app.Grid_11);
            app.metaData_11 = uieditfield(app.Grid_12, 'text');
            app.metaData_11.Editable = 'off';
            app.metaData_11.Enable = 'off';
            app.metaData_11.Value = 'CRFS BIN v.5';
            app.FormatodoarquivoLabel = uilabel(app.Grid_12);
            app.FormatodoarquivoLabel.Enable = 'off';
            app.FormatodoarquivoLabel.Text = 'Formato do arquivo:';
            app.metaData_12 = uieditfield(app.Grid_12, 'text');
            app.metaData_12.Editable = 'off';
            app.metaData_12.Enable = 'off';
            app.metaData_12.Value = 'RFeye002234';
            app.IntrumentoEditFieldLabel = uilabel(app.Grid_12);
            app.IntrumentoEditFieldLabel.Enable = 'off';
            app.IntrumentoEditFieldLabel.Text = 'Intrumento:';
            app.ThreadIDDropDownLabel = uilabel(app.Grid_12);
            app.ThreadIDDropDownLabel.Enable = 'off';
            app.ThreadIDDropDownLabel.Text = 'Thread ID:';
            app.metaData_13 = uidropdown(app.Grid_12);
            app.metaData_13.Items = {'10', '50', '90'};
            app.metaData_13.ValueChangedFcn = createCallbackFcn(app, @SelectThreaID, true);
            app.metaData_13.Enable = 'off';
            app.metaData_13.Value = '10';
            app.metaData_14 = uieditfield(app.Grid_12, 'text');
            app.metaData_14.Editable = 'off';
            app.metaData_14.Enable = 'off';
            app.metaData_14.Value = '88.000 - 108.000 MHz';
            app.FaixadefrequnciaEditFieldLabel = uilabel(app.Grid_12);
            app.FaixadefrequnciaEditFieldLabel.Enable = 'off';
            app.FaixadefrequnciaEditFieldLabel.Text = 'Faixa de frequência:';
            app.metaData_15 = uitextarea(app.Grid_12);
            app.metaData_15.Editable = 'off';
            app.metaData_15.Enable = 'off';
            app.DescrioTextAreaLabel = uilabel(app.Grid_12);
            app.DescrioTextAreaLabel.Enable = 'off';
            app.DescrioTextAreaLabel.Text = 'Descrição:';
            app.GridLayout8 = uigridlayout(app.Grid1);
            app.Button_35 = uibutton(app.GridLayout8, 'push');
            app.Button_35.ButtonPushedFcn = createCallbackFcn(app, @Button_35Pushed, true);
            app.Button_35.Icon = 'Help.png';
            app.Button_35.Text = '';
            app.Button_34 = uibutton(app.GridLayout8, 'push');
            app.Button_34.ButtonPushedFcn = createCallbackFcn(app, @Button_34Pushed, true);
            app.Button_34.Icon = 'InfoIcon7.png';
            app.Button_34.Text = '';
            app.Button_33 = uibutton(app.GridLayout8, 'push');
            app.Button_33.Icon = 'settings2.png';
            app.Button_33.Text = '';
            app.Button_32 = uibutton(app.GridLayout8, 'push');
            app.Button_32.ButtonPushedFcn = createCallbackFcn(app, @globalVariables_Callback, true);
            app.Button_32.Icon = 'global-icon-13.png';
            app.Button_32.Text = '';
            app.Button_31 = uibutton(app.GridLayout8, 'push');
            app.Button_31.ButtonPushedFcn = createCallbackFcn(app, @winLayout_Callback, true);
            app.Button_31.Icon = 'a3.png';
            app.Button_31.Text = '';
            app.Image = uiimage(app.Grid1);
            app.Image.Enable = 'off';
            app.Image.ImageSource = '_Logo3A_200.png';
            app.Panel3_Info = uipanel(app.Grid1);
            app.Panel3_Info.Title = 'FLUXO DE DADOS SELECIONADO';
            app.Info_Grid1 = uigridlayout(app.Panel3_Info);
            app.Info_Panel2 = uipanel(app.Info_Grid1);
            app.Info_Grid3 = uigridlayout(app.Info_Panel2);
            app.LongitudeEditField_2Label = uilabel(app.Info_Grid3);
            app.LongitudeEditField_2Label.Text = 'Longitude:';
            app.Info_Latitude = uieditfield(app.Info_Grid3, 'numeric');
            app.Info_Latitude.ValueDisplayFormat = '%.6f';
            app.Info_Latitude.Value = -12.345762;
            app.Info_Longitude = uieditfield(app.Info_Grid3, 'numeric');
            app.Info_Longitude.ValueDisplayFormat = '%.6f';
            app.Info_Longitude.Value = -12.345762;
            app.LatitudeEditField_2Label = uilabel(app.Info_Grid3);
            app.LatitudeEditField_2Label.Text = 'Latitude:';
            app.Info_CheckBox = uicheckbox(app.Info_Grid3);
            app.Info_CheckBox.Text = 'Transportar esses valores para outros fluxos de dados do instrumento, caso existentes.';
            app.Info_CheckBox.Value = true;
            app.Info_OCC = uidropdown(app.Info_Grid3);
            app.Info_OCC.Items = {'Ocupação a ser aferida', 'ThreadID 11: -90 dBm (threshold)', 'ThreadID 12: -85 dBm (threshold)', 'ThreadID 13: -95 dBm (threshold)'};
            app.Info_OCC.Value = 'ThreadID 11: -90 dBm (threshold)';
            app.OcupaoDropDownLabel = uilabel(app.Info_Grid3);
            app.OcupaoDropDownLabel.Text = 'Ocupação:';
            app.Info_Button = uibutton(app.Info_Grid1, 'push');
            app.Info_Button.ButtonPushedFcn = createCallbackFcn(app, @Info_ButtonPushed, true);
            app.Info_Button.Icon = 'Edit.png';
            app.Info_Button.Text = '';
            app.IntrumentoEditFieldLabel_2 = uilabel(app.Info_Grid1);
            app.IntrumentoEditFieldLabel_2.Text = 'Intrumento:';
            app.FluxodedadosLabel = uilabel(app.Info_Grid1);
            app.FluxodedadosLabel.Text = 'Fluxo de dados:';
            app.Info_Label2 = uilabel(app.Info_Grid1);
            app.Info_Label2.Text = 'Informações passíveis de edição';
            app.Info_Label1 = uilabel(app.Info_Grid1);
            app.Info_Label1.Text = 'Informações extraídas de base de dados da Agência';
            app.Info_MetaData1 = uieditfield(app.Info_Grid1, 'text');
            app.Info_MetaData1.Editable = 'off';
            app.Info_MetaData1.Value = 'RFeye002234';
            app.Info_MetaData2 = uieditfield(app.Info_Grid1, 'text');
            app.Info_MetaData2.Editable = 'off';
            app.Info_MetaData2.Value = 'ThreadID 10: 88.000 - 108.000 MHz';
            app.Info_Panel1 = uipanel(app.Info_Grid1);
            app.Info_Grid2 = uigridlayout(app.Info_Panel1);
            app.LongitudeEditFieldLabel = uilabel(app.Info_Grid2);
            app.LongitudeEditFieldLabel.Text = 'Longitude:';
            app.Info_MetaData6 = uieditfield(app.Info_Grid2, 'numeric');
            app.Info_MetaData6.ValueDisplayFormat = '%.6f';
            app.Info_MetaData6.Editable = 'off';
            app.Info_MetaData6.Value = -35.092938;
            app.Info_MetaData5 = uieditfield(app.Info_Grid2, 'numeric');
            app.Info_MetaData5.ValueDisplayFormat = '%.6f';
            app.Info_MetaData5.Editable = 'off';
            app.Info_MetaData5.Value = -12.345762;
            app.LatitudeEditFieldLabel = uilabel(app.Info_Grid2);
            app.LatitudeEditFieldLabel.Text = 'Latitude:';
            app.Info_MetaData4 = uieditfield(app.Info_Grid2, 'text');
            app.Info_MetaData4.Editable = 'off';
            app.Info_MetaData4.Value = 'Salvador/BA';
            app.LocalidadeLabel = uilabel(app.Info_Grid2);
            app.LocalidadeLabel.Text = 'Localidade:';
            app.Info_MetaData3 = uieditfield(app.Info_Grid2, 'text');
            app.Info_MetaData3.Editable = 'off';
            app.Info_MetaData3.Value = 'GR08';
            app.UnidaderesponsvelLabel_2 = uilabel(app.Info_Grid2);
            app.UnidaderesponsvelLabel_2.Text = 'Unidade responsável:';
            app.Panel3_Report = uipanel(app.Grid1);
            app.Panel3_Report.Title = 'RELATÓRIO DE MONITORAÇÃO';
            app.Panel3_Report.Visible = 'off';
            app.GridLayout = uigridlayout(app.Panel3_Report);
            app.Panel3_TabGroup = uitabgroup(app.GridLayout);
            app.Report_Tab1 = uitab(app.Panel3_TabGroup);
            app.Report_Tab1.Title = 'α';
            app.Report_Grid1 = uigridlayout(app.Report_Tab1);
            app.InspeoSistemaFiscalizaEditFieldLabel = uilabel(app.Report_Grid1);
            app.InspeoSistemaFiscalizaEditFieldLabel.Text = 'Inspeção (Sistema Fiscaliza):';
            app.UnidaderesponsvelLabel = uilabel(app.Report_Grid1);
            app.UnidaderesponsvelLabel.Text = 'Unidade responsável:';
            app.Report_Value1 = uidropdown(app.Report_Grid1);
            app.Report_Value1.Items = {'GR01 (SP)', 'GR02 (RJ)', 'GR03 (PR)', 'GR04 (MG)', 'GR05 (RS)', 'GR06 (PE)', 'GR07 (GO)', 'GR08 (BA)', 'GR09 (CE)', 'GR10 (PA)', 'GR11 (AM)', 'UO00.1 (DF)', 'UO02.1 (ES)', 'UO03.1 (SC)', 'UO06.1 (AL)', 'UO06.2 (PB)', 'UO07.1 (MT)', 'UO07.2 (MS)', 'UO07.3 (TO)', 'UO08.1 (SE)', 'UO09.1 (RN)', 'UO09.2 (PI)', 'UO10.1 (MA)', 'UO10.2 (AP)', 'UO11.1 (RO)', 'UO11.2 (AC)', 'UO11.3 (RR)'};
            app.Report_Value1.Value = 'GR01 (SP)';
            app.Report_Value2 = uieditfield(app.Report_Grid1, 'numeric');
            app.Report_Value2.Limits = [1 Inf];
            app.Report_Value2.ValueDisplayFormat = '%d';
            app.Report_Value2.Value = 24538;
            app.Report_Label1 = uilabel(app.Report_Grid1);
            app.Report_Label1.Text = 'Layout e informações textuais:';
            app.Report_Panel1 = uipanel(app.Report_Grid1);
            app.Report_Grid2 = uigridlayout(app.Report_Panel1);
            app.Report_CheckBox1 = uicheckbox(app.Report_Grid2);
            app.Report_CheckBox1.Text = 'Incluir cabeçalho de estilo (HTML).';
            app.Report_CheckBox1.Value = true;
            app.Report_Label2 = uilabel(app.Report_Grid2);
            app.Report_Label2.Text = 'Em relação ao item intitulado "Introdução":';
            app.Report_CheckBox2 = uicheckbox(app.Report_Grid2);
            app.Report_CheckBox2.ValueChangedFcn = createCallbackFcn(app, @Report_CheckBox2ValueChanged, true);
            app.Report_CheckBox2.Text = 'Incluir detalhes acerca do PMEC.';
            app.Report_CheckBox2.Value = true;
            app.Report_CheckBox3 = uicheckbox(app.Report_Grid2);
            app.Report_CheckBox3.ValueChangedFcn = createCallbackFcn(app, @Report_CheckBox3ValueChanged, true);
            app.Report_CheckBox3.Text = 'Incluir detalhes acerca do PRD.';
            app.Report_CheckBox4 = uicheckbox(app.Report_Grid2);
            app.Report_CheckBox4.ValueChangedFcn = createCallbackFcn(app, @Report_CheckBox4ValueChanged, true);
            app.Report_CheckBox4.Text = 'Incluir a informação textual indicada a seguir.';
            app.Report_TextArea = uitextarea(app.Report_Grid2);
            app.Report_TextArea.Enable = 'off';
            app.Report_TextArea.Value = {'O Ministério das Comunicações solicitou, por meio do Ofício nº XX/2021-MC, a realização de monitoração da faixa 450-470 MHz.'};
            app.Report_CheckBox5 = uicheckbox(app.Report_Grid2);
            app.Report_CheckBox5.Text = 'Incluir item editável intitulado "Análise".';
            app.Report_Tab2 = uitab(app.Panel3_TabGroup);
            app.Report_Tab2.Title = 'β';
            app.Report_Grid3 = uigridlayout(app.Report_Tab2);
            app.Report_Panel2 = uipanel(app.Report_Grid3);
            app.Report_Grid5 = uigridlayout(app.Report_Panel2);
            app.TipoDropDownLabel = uilabel(app.Report_Grid5);
            app.TipoDropDownLabel.Text = 'Tipo:';
            app.TempodeintegraoLabel = uilabel(app.Report_Grid5);
            app.TempodeintegraoLabel.Text = 'Tempo de integração:';
            app.Report_Unit5 = uilabel(app.Report_Grid5);
            app.Report_Unit5.Text = 'dB';
            app.Report_Unit4 = uilabel(app.Report_Grid5);
            app.Report_Unit4.Text = 'min';
            app.Report_Label6 = uilabel(app.Report_Grid5);
            app.Report_Label6.Text = 'Estimativa do piso de ruído:';
            app.Report_Label5 = uilabel(app.Report_Grid5);
            app.Report_Label5.Text = 'Threshold:';
            app.Report_Value3 = uidropdown(app.Report_Grid5);
            app.Report_Value3.Items = {'Fixo', 'Adaptativo'};
            app.Report_Value3.Enable = 'off';
            app.Report_Value3.Value = 'Fixo';
            app.Report_Value4 = uispinner(app.Report_Grid5);
            app.Report_Value4.Limits = [1 60];
            app.Report_Value4.Value = 60;
            app.Report_Value7 = uispinner(app.Report_Grid5);
            app.Report_Value7.Limits = [0 10];
            app.AmostrasdescartadasLabel = uilabel(app.Report_Grid5);
            app.AmostrasdescartadasLabel.Text = {'Amostras '; 'descartadas (%):'};
            app.Report_Value8 = uispinner(app.Report_Grid5);
            app.Report_Value8.Limits = [10 90];
            app.Report_Value8.Value = 20;
            app.AmostrasteisLabel = uilabel(app.Report_Grid5);
            app.AmostrasteisLabel.Text = {'Amostras '; 'úteis (%):'};
            app.Report_Value6 = uidropdown(app.Report_Grid5);
            app.Report_Value6.Items = {'Mediana', 'Média'};
            app.Report_Value6.Value = 'Mediana';
            app.FunoestatsticaDropDownLabel = uilabel(app.Report_Grid5);
            app.FunoestatsticaDropDownLabel.Text = 'Função estatística:';
            app.Report_Value5 = uispinner(app.Report_Grid5);
            app.Report_Value5.Limits = [5 12];
            app.Report_Value5.Value = 12;
            app.OffSetLabel_2 = uilabel(app.Report_Grid5);
            app.OffSetLabel_2.Text = 'OffSet:';
            app.Report_Label4 = uilabel(app.Report_Grid3);
            app.Report_Label4.Text = 'Parâmetros relacionados à ocupação (caso aplicável)';
            app.Report_Label3 = uilabel(app.Report_Grid3);
            app.Report_Label3.Text = 'Período de observação a processar';
            app.Report_Grid4 = uigridlayout(app.Report_Grid3);
            app.Report_DatePicker1 = uidatepicker(app.Report_Grid4);
            app.Report_DatePicker1.DisplayFormat = 'dd/MMM/uuuu';
            app.Report_Spinner1 = uispinner(app.Report_Grid4);
            app.Report_Spinner1.Limits = [0 23];
            app.Report_Spinner1.RoundFractionalValues = 'on';
            app.Report_Spinner1.ValueDisplayFormat = '%.0f';
            app.Report_Spinner2 = uispinner(app.Report_Grid4);
            app.Report_Spinner2.Step = 10;
            app.Report_Spinner2.Limits = [0 59];
            app.Report_Spinner2.RoundFractionalValues = 'on';
            app.Report_Spinner2.ValueDisplayFormat = '%.0f';
            app.Report_DatePicker2 = uidatepicker(app.Report_Grid4);
            app.Report_DatePicker2.DisplayFormat = 'dd/MMM/uuuu';
            app.Report_Spinner3 = uispinner(app.Report_Grid4);
            app.Report_Spinner3.Limits = [0 23];
            app.Report_Spinner3.RoundFractionalValues = 'on';
            app.Report_Spinner3.ValueDisplayFormat = '%.0f';
            app.Report_Spinner3.Value = 23;
            app.Report_Spinner4 = uispinner(app.Report_Grid4);
            app.Report_Spinner4.Step = 10;
            app.Report_Spinner4.Limits = [0 59];
            app.Report_Spinner4.RoundFractionalValues = 'on';
            app.Report_Spinner4.ValueDisplayFormat = '%.0f';
            app.Report_Spinner4.Value = 59;
            app.Report_Tab3 = uitab(app.Panel3_TabGroup);
            app.Report_Tab3.Title = 'γ';
            app.Report_Grid6 = uigridlayout(app.Report_Tab3);
            app.Panel_6 = uipanel(app.Report_Grid6);
            app.Report_Grid10 = uigridlayout(app.Panel_6);
            app.Report_Unit16 = uilabel(app.Report_Grid10);
            app.Report_Unit16.Enable = 'off';
            app.Report_Unit16.Text = '%';
            app.Report_Unit15 = uilabel(app.Report_Grid10);
            app.Report_Unit15.Enable = 'off';
            app.Report_Unit15.Text = 'kHz';
            app.Report_Unit14 = uilabel(app.Report_Grid10);
            app.Report_Unit14.Enable = 'off';
            app.Report_Unit14.Text = 'kHz';
            app.Report_Unit13 = uilabel(app.Report_Grid10);
            app.Report_Unit13.Enable = 'off';
            app.Report_Unit13.Text = 'dB';
            app.Report_Unit12 = uilabel(app.Report_Grid10);
            app.Report_Unit12.Enable = 'off';
            app.Report_Unit12.Text = 'kHz';
            app.Report_Unit11 = uilabel(app.Report_Grid10);
            app.Report_Unit11.Enable = 'off';
            app.Report_Unit11.Text = 'kHz';
            app.Report_Unit10 = uilabel(app.Report_Grid10);
            app.Report_Unit10.Enable = 'off';
            app.Report_Unit10.Text = 'dB';
            app.ProeminnciamnimaemrelaoadjacnciaLabel = uilabel(app.Report_Grid10);
            app.ProeminnciamnimaemrelaoadjacnciaLabel.Enable = 'off';
            app.ProeminnciamnimaemrelaoadjacnciaLabel.Text = 'Proeminência mínima em relação à adjacência:';
            app.LarguradebandamnimaLabel = uilabel(app.Report_Grid10);
            app.LarguradebandamnimaLabel.Enable = 'off';
            app.LarguradebandamnimaLabel.Text = 'Largura de banda mínima:';
            app.DistnciamnimaentrepicosLabel_2 = uilabel(app.Report_Grid10);
            app.DistnciamnimaentrepicosLabel_2.Enable = 'off';
            app.DistnciamnimaentrepicosLabel_2.Text = 'Distância mínima entre picos:';
            app.ProeminnciamnimaemrelaoadjacnciaLabel_2 = uilabel(app.Report_Grid10);
            app.ProeminnciamnimaemrelaoadjacnciaLabel_2.Enable = 'off';
            app.ProeminnciamnimaemrelaoadjacnciaLabel_2.Text = 'Proeminência mínima em relação à adjacência:';
            app.LarguradebandamnimaLabel_2 = uilabel(app.Report_Grid10);
            app.LarguradebandamnimaLabel_2.Enable = 'off';
            app.LarguradebandamnimaLabel_2.Text = 'Largura de banda mínima:';
            app.DistnciamnimaentrepicosLabel = uilabel(app.Report_Grid10);
            app.DistnciamnimaentrepicosLabel.Enable = 'off';
            app.DistnciamnimaentrepicosLabel.Text = 'Distância mínima entre picos:';
            app.OcupaomnimaLabel = uilabel(app.Report_Grid10);
            app.OcupaomnimaLabel.Enable = 'off';
            app.OcupaomnimaLabel.Text = 'Ocupação mínima:';
            app.Report_Value9 = uidropdown(app.Report_Grid10);
            app.Report_Value9.Items = {'Média', 'Mediana'};
            app.Report_Value9.Enable = 'off';
            app.Report_Value9.Value = 'Mediana';
            app.Report_Value10 = uispinner(app.Report_Grid10);
            app.Report_Value10.Step = 10;
            app.Report_Value10.Limits = [1 Inf];
            app.Report_Value10.RoundFractionalValues = 'on';
            app.Report_Value10.ValueDisplayFormat = '%.0f';
            app.Report_Value10.Enable = 'off';
            app.Report_Value10.Value = 10;
            app.Report_Value11 = uispinner(app.Report_Grid10);
            app.Report_Value11.Step = 10;
            app.Report_Value11.Limits = [0 Inf];
            app.Report_Value11.RoundFractionalValues = 'on';
            app.Report_Value11.ValueDisplayFormat = '%.0f';
            app.Report_Value11.Enable = 'off';
            app.Report_Value11.Value = 10;
            app.Report_Value12 = uispinner(app.Report_Grid10);
            app.Report_Value12.Step = 10;
            app.Report_Value12.Limits = [0 Inf];
            app.Report_Value12.RoundFractionalValues = 'on';
            app.Report_Value12.ValueDisplayFormat = '%.0f';
            app.Report_Value12.Enable = 'off';
            app.Report_Value12.Value = 25;
            app.Report_CheckBox6 = uicheckbox(app.Report_Grid10);
            app.Report_CheckBox6.ValueChangedFcn = createCallbackFcn(app, @Report_CheckBox6ValueChanged, true);
            app.Report_CheckBox6.Text = 'Parâmetros ajustados automaticamente.';
            app.Report_CheckBox6.Value = true;
            app.Report_Value13 = uispinner(app.Report_Grid10);
            app.Report_Value13.Step = 10;
            app.Report_Value13.Limits = [1 Inf];
            app.Report_Value13.RoundFractionalValues = 'on';
            app.Report_Value13.ValueDisplayFormat = '%.0f';
            app.Report_Value13.Enable = 'off';
            app.Report_Value13.Value = 30;
            app.FunoestatsticaDropDown_2Label = uilabel(app.Report_Grid10);
            app.FunoestatsticaDropDown_2Label.Enable = 'off';
            app.FunoestatsticaDropDown_2Label.Text = 'Função estatística:';
            app.Report_Value14 = uispinner(app.Report_Grid10);
            app.Report_Value14.Step = 10;
            app.Report_Value14.Limits = [0 Inf];
            app.Report_Value14.RoundFractionalValues = 'on';
            app.Report_Value14.ValueDisplayFormat = '%.0f';
            app.Report_Value14.Enable = 'off';
            app.Report_Value14.Value = 10;
            app.Report_Value15 = uispinner(app.Report_Grid10);
            app.Report_Value15.Step = 10;
            app.Report_Value15.Limits = [0 Inf];
            app.Report_Value15.RoundFractionalValues = 'on';
            app.Report_Value15.ValueDisplayFormat = '%.0f';
            app.Report_Value15.Enable = 'off';
            app.Report_Value15.Value = 25;
            app.Report_Value16 = uispinner(app.Report_Grid10);
            app.Report_Value16.Step = 10;
            app.Report_Value16.Limits = [0 Inf];
            app.Report_Value16.RoundFractionalValues = 'on';
            app.Report_Value16.ValueDisplayFormat = '%.0f';
            app.Report_Value16.Enable = 'off';
            app.Report_Value16.Value = 1;
            app.Report_CheckBox7 = uicheckbox(app.Report_Grid10);
            app.Report_CheckBox7.ValueChangedFcn = createCallbackFcn(app, @Report_CheckBox7ValueChanged, true);
            app.Report_CheckBox7.Text = 'Parâmetros ajustados automaticamente.';
            app.Report_CheckBox7.Value = true;
            app.Report_Label8 = uilabel(app.Report_Grid10);
            app.Report_Label8.Text = 'Critério 1: na curva de tendência central (mediana ou média), identificam-se os picos espaçados entre si de uma distância mínima que possuam dada largura de banda e proeminência em relação à adjacência:';
            app.Report_Label9 = uilabel(app.Report_Grid10);
            app.Report_Label9.Text = 'Critério 2: na curva de máximo, aplica-se metodologia análoga à descrita no Critério 1, acrescido de uma avaliação da ocupação.';
            app.Report_Label7 = uilabel(app.Report_Grid6);
            app.Report_Label7.Text = 'Parâmetros relacionados à identificação de emissões:';
            app.Button_31r = uibutton(app.GridLayout, 'push');
            app.Button_31r.ButtonPushedFcn = createCallbackFcn(app, @Button_31rPushed, true);
            app.Button_31r.Icon = 'Media_Play.png';
            app.Button_31r.Enable = 'off';
            app.Button_31r.Text = '';
            app.Panel3_Spec = uipanel(app.Grid1);
            app.Panel3_Spec.Title = 'CONFIGURAÇÕES';
            app.Grid31 = uigridlayout(app.Panel3_Spec);
            app.TabGroup = uitabgroup(app.Grid31);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'α';
            app.GridLayout2 = uigridlayout(app.Tab);
            app.CheckBox_05 = uicheckbox(app.GridLayout2);
            app.CheckBox_05.ValueChangedFcn = createCallbackFcn(app, @CheckBox32_2ValueChanged, true);
            app.CheckBox_05.Enable = 'off';
            app.CheckBox_05.Text = 'Waterfall';
            app.Panel_14 = uipanel(app.GridLayout2);
            app.GridLayout7 = uigridlayout(app.Panel_14);
            app.Report_Value6_3 = uidropdown(app.GridLayout7);
            app.Report_Value6_3.Items = {'68', '95', '99'};
            app.Report_Value6_3.ValueChangedFcn = createCallbackFcn(app, @Report_Value6_2ValueChanged, true);
            app.Report_Value6_3.Enable = 'off';
            app.Report_Value6_3.Value = '99';
            app.Report_Value8_2 = uispinner(app.GridLayout7);
            app.Report_Value8_2.Step = 10;
            app.Report_Value8_2.Limits = [10 100];
            app.Report_Value8_2.RoundFractionalValues = 'on';
            app.Report_Value8_2.ValueDisplayFormat = '%.0f';
            app.Report_Value8_2.ValueChangedFcn = createCallbackFcn(app, @Report_Value6_2ValueChanged, true);
            app.Report_Value8_2.Enable = 'off';
            app.Report_Value8_2.Value = 80;
            app.ThresholdDropDownLabel = uilabel(app.GridLayout7);
            app.ThresholdDropDownLabel.Text = 'Threshold:';
            app.OffSetLabel = uilabel(app.GridLayout7);
            app.OffSetLabel.Enable = 'off';
            app.OffSetLabel.Text = 'OffSet:';
            app.FatordeintegraoLabel_2 = uilabel(app.GridLayout7);
            app.FatordeintegraoLabel_2.Text = 'Fator de integração:';
            app.ValorLabel = uilabel(app.GridLayout7);
            app.ValorLabel.Text = 'Valor:';
            app.Label_29 = uilabel(app.GridLayout7);
            app.Label_29.Enable = 'off';
            app.Label_29.Text = 'dB';
            app.Report_Value5_3 = uispinner(app.GridLayout7);
            app.Report_Value5_3.Limits = [0 12];
            app.Report_Value5_3.ValueChangedFcn = createCallbackFcn(app, @Report_Value6_2ValueChanged, true);
            app.Report_Value5_3.Enable = 'off';
            app.Report_Value5_3.Value = 12;
            app.Report_Value6_2 = uidropdown(app.GridLayout7);
            app.Report_Value6_2.Items = {'Moda', 'Mediana', 'Média (vídeo)'};
            app.Report_Value6_2.ValueChangedFcn = createCallbackFcn(app, @Report_Value6_2ValueChanged, true);
            app.Report_Value6_2.Enable = 'off';
            app.Report_Value6_2.Value = 'Mediana';
            app.Value31_3 = uispinner(app.GridLayout7);
            app.Value31_3.Limits = [2 Inf];
            app.Value31_3.ValueDisplayFormat = '%d';
            app.Value31_3.ValueChangedFcn = createCallbackFcn(app, @Report_Value6_2ValueChanged, true);
            app.Value31_3.Value = 100;
            app.THR_Unit = uilabel(app.GridLayout7);
            app.THR_Unit.Text = 'dBm';
            app.THR_Value = uispinner(app.GridLayout7);
            app.THR_Value.ValueDisplayFormat = '%d';
            app.THR_Value.ValueChangedFcn = createCallbackFcn(app, @Report_Value6_2ValueChanged, true);
            app.THR_Value.Value = -80;
            app.FunoestatsticaDropDownLabel_3 = uilabel(app.GridLayout7);
            app.FunoestatsticaDropDownLabel_3.Enable = 'off';
            app.FunoestatsticaDropDownLabel_3.Text = 'Função estatística:';
            app.AmostrasteisLabel_2 = uilabel(app.GridLayout7);
            app.AmostrasteisLabel_2.Enable = 'off';
            app.AmostrasteisLabel_2.Text = {'Amostras '; 'úteis (%):'};
            app.NveldeconfianaLabel = uilabel(app.GridLayout7);
            app.NveldeconfianaLabel.Enable = 'off';
            app.NveldeconfianaLabel.Text = {'Nível de '; 'confiança (%):'};
            app.THR_Type = uidropdown(app.GridLayout7);
            app.THR_Type.Items = {'Linear', 'Estimativa do piso de ruído acrescida de OffSet'};
            app.THR_Type.ValueChangedFcn = createCallbackFcn(app, @THR_TypeValueChanged, true);
            app.THR_Type.Value = 'Linear';
            app.CheckBox_04 = uicheckbox(app.GridLayout2);
            app.CheckBox_04.ValueChangedFcn = createCallbackFcn(app, @CheckBox32_2ValueChanged, true);
            app.CheckBox_04.Text = 'Ocupação em "tempo real"';
            app.Panel_13 = uipanel(app.GridLayout2);
            app.GridLayout4 = uigridlayout(app.Panel_13);
            app.CheckBox_03 = uicheckbox(app.GridLayout4);
            app.CheckBox_03.ValueChangedFcn = createCallbackFcn(app, @axesFlag_Callback, true);
            app.CheckBox_03.Text = 'MaxHold';
            app.CheckBox_02 = uicheckbox(app.GridLayout4);
            app.CheckBox_02.ValueChangedFcn = createCallbackFcn(app, @axesFlag_Callback, true);
            app.CheckBox_02.Text = 'MinHold';
            app.Label_26 = uilabel(app.GridLayout4);
            app.Label_26.Text = 'Limites:';
            app.RadioButtonGroup_02 = uibuttongroup(app.GridLayout4);
            app.RadioButtonGroup_02.SelectionChangedFcn = createCallbackFcn(app, @axesFlag_Callback, true);
            app.Value_01 = uispinner(app.RadioButtonGroup_02);
            app.Value_01.Limits = [2 Inf];
            app.Value_01.ValueDisplayFormat = '%d';
            app.Value_01.ValueChangedFcn = createCallbackFcn(app, @axesFlag_Callback, true);
            app.Value_01.Enable = 'off';
            app.Value_01.Value = 100;
            app.RadioButton_05 = uiradiobutton(app.RadioButtonGroup_02);
            app.RadioButton_05.Text = 'Média (vídeo)';
            app.FatordeintegraoLabel = uilabel(app.RadioButtonGroup_02);
            app.FatordeintegraoLabel.Enable = 'off';
            app.FatordeintegraoLabel.Text = 'Fator de integração:';
            app.RadioButton_04 = uiradiobutton(app.RadioButtonGroup_02);
            app.RadioButton_04.Text = 'Mediana';
            app.RadioButton_04.Value = true;
            app.RadioButton_03 = uiradiobutton(app.RadioButtonGroup_02);
            app.RadioButton_03.Text = 'Moda';
            app.CheckBox_01 = uicheckbox(app.GridLayout4);
            app.CheckBox_01.ValueChangedFcn = createCallbackFcn(app, @axesFlag_Callback, true);
            app.CheckBox_01.Text = 'Tendência central:';
            app.RadioButtonGroup_01 = uibuttongroup(app.GridLayout2);
            app.RadioButtonGroup_01.SelectionChangedFcn = createCallbackFcn(app, @integrationType_Callback, true);
            app.RadioButton_02 = uiradiobutton(app.RadioButtonGroup_01);
            app.RadioButton_02.Text = '"Tempo real"';
            app.RadioButton_01 = uiradiobutton(app.RadioButtonGroup_01);
            app.RadioButton_01.Text = 'Integraçao infinita';
            app.RadioButton_01.Value = true;
            app.Label_01 = uilabel(app.GridLayout2);
            app.Label_01.Text = 'Processamento dos traços';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'β';
            app.GridLayout6 = uigridlayout(app.Tab_2);
            app.Label32 = uilabel(app.GridLayout6);
            app.Label32.Text = 'Busca por picos';
            app.ButtonGroup31 = uibuttongroup(app.GridLayout6);
            app.ButtonGroup31.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup31SelectionChanged, true);
            app.Button33 = uiradiobutton(app.ButtonGroup31);
            app.Button33.Enable = 'off';
            app.Button33.Text = 'Manual';
            app.Button32 = uiradiobutton(app.ButtonGroup31);
            app.Button32.Text = 'Pico';
            app.Button31 = uiradiobutton(app.ButtonGroup31);
            app.Button31.Text = 'Excursão de pico';
            app.Button31.Value = true;
            app.Pk_Value1 = uispinner(app.GridLayout6);
            app.Pk_Value1.Limits = [1 10];
            app.Pk_Value1.RoundFractionalValues = 'on';
            app.Pk_Value1.ValueDisplayFormat = '%.0f';
            app.Pk_Value1.ValueChangedFcn = createCallbackFcn(app, @Pk_Value1ValueChanged, true);
            app.Pk_Value1.Value = 1;
            app.PicosLabel = uilabel(app.GridLayout6);
            app.PicosLabel.Text = 'Picos:';
            app.DistnciaLabel = uilabel(app.GridLayout6);
            app.DistnciaLabel.Text = 'Distância:';
            app.Pk_Value2 = uispinner(app.GridLayout6);
            app.Pk_Value2.Step = 10;
            app.Pk_Value2.Limits = [0 Inf];
            app.Pk_Value2.RoundFractionalValues = 'on';
            app.Pk_Value2.ValueDisplayFormat = '%.0f';
            app.Pk_Unit2 = uilabel(app.GridLayout6);
            app.Pk_Unit2.Text = 'kHz';
            app.Pk_Value3 = uispinner(app.GridLayout6);
            app.Pk_Value3.Step = 10;
            app.Pk_Value3.Limits = [0 Inf];
            app.Pk_Value3.RoundFractionalValues = 'on';
            app.Pk_Value3.ValueDisplayFormat = '%.0f';
            app.LarguradebandaLabel = uilabel(app.GridLayout6);
            app.LarguradebandaLabel.Text = 'Largura de banda:';
            app.Pk_Unit3 = uilabel(app.GridLayout6);
            app.Pk_Unit3.Text = 'kHz';
            app.Pk_Value4 = uispinner(app.GridLayout6);
            app.Pk_Value4.Step = 10;
            app.Pk_Value4.Limits = [0 Inf];
            app.Pk_Value4.RoundFractionalValues = 'on';
            app.Pk_Value4.ValueDisplayFormat = '%.0f';
            app.ProeminnciaLabel = uilabel(app.GridLayout6);
            app.ProeminnciaLabel.Text = 'Proeminência:';
            app.Pk_Unit4 = uilabel(app.GridLayout6);
            app.Pk_Unit4.Text = 'dB';
            app.Pk_Value5 = uispinner(app.GridLayout6);
            app.Pk_Value5.Step = 10;
            app.Pk_Value5.RoundFractionalValues = 'on';
            app.Pk_Value5.ValueDisplayFormat = '%.0f';
            app.Pk_Value5.Value = -Inf;
            app.ThresholdLabel = uilabel(app.GridLayout6);
            app.ThresholdLabel.Text = 'Threshold:';
            app.Pk_Unit5 = uilabel(app.GridLayout6);
            app.Pk_Unit5.Text = 'dBm';
            app.Pk_Label1 = uilabel(app.GridLayout6);
            app.Pk_Label1.Text = 'Marcador 1:';
            app.Pk_Value6 = uieditfield(app.GridLayout6, 'numeric');
            app.Pk_Value6.ValueDisplayFormat = '%.3f';
            app.Pk_Value6.Editable = 'off';
            app.Pk_Unit6 = uilabel(app.GridLayout6);
            app.Pk_Unit6.Text = 'MHz';
            app.Pk_Value7 = uieditfield(app.GridLayout6, 'numeric');
            app.Pk_Value7.ValueDisplayFormat = '%.2f';
            app.Pk_Value7.Editable = 'off';
            app.Pk_Unit7 = uilabel(app.GridLayout6);
            app.Pk_Unit7.Text = 'dBm';
            app.Pk_Label2 = uilabel(app.GridLayout6);
            app.Pk_Label2.Text = 'Controle do marcador:';
            app.Pk_Value8 = uispinner(app.GridLayout6);
            app.Pk_Value8.Limits = [2 Inf];
            app.Pk_Value8.RoundFractionalValues = 'on';
            app.Pk_Value8.ValueDisplayFormat = '%.0f';
            app.Pk_Value8.Value = 2;
            app.Pk_Value9 = uieditfield(app.GridLayout6, 'numeric');
            app.Pk_Value9.ValueDisplayFormat = '%.3f';
            app.Pk_Value9.Editable = 'off';
            app.Pk_Unit8 = uilabel(app.GridLayout6);
            app.Pk_Unit8.Text = 'MHz';
            app.Pk_Value10 = uieditfield(app.GridLayout6, 'numeric');
            app.Pk_Value10.ValueDisplayFormat = '%.2f';
            app.Pk_Value10.Editable = 'off';
            app.Pk_Unit9 = uilabel(app.GridLayout6);
            app.Pk_Unit9.Text = 'dBm';
            app.CheckBox34 = uicheckbox(app.GridLayout6);
            app.CheckBox34.Text = 'Delta';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = 'γ';
            app.GridLayout9 = uigridlayout(app.Tab_3);
            app.Label31_5 = uilabel(app.GridLayout9);
            app.Label31_5.Text = 'Interatividade';
            app.BarrademenuCheckBox = uicheckbox(app.GridLayout9);
            app.BarrademenuCheckBox.ValueChangedFcn = createCallbackFcn(app, @BarrademenuCheckBoxValueChanged, true);
            app.BarrademenuCheckBox.Text = 'Barra de menu';
            app.BarradeferramentasCheckBox = uicheckbox(app.GridLayout9);
            app.BarradeferramentasCheckBox.ValueChangedFcn = createCallbackFcn(app, @BarradeferramentasCheckBoxValueChanged, true);
            app.BarradeferramentasCheckBox.Text = 'Barra de ferramentas';
            app.Slider31 = uislider(app.GridLayout9);
            app.Slider31.MajorTicks = [0 25 50 75 100];
            app.Slider31.MajorTickLabels = {'0', '25', '50', '75', '100'};
            app.Slider31.ValueChangingFcn = createCallbackFcn(app, @Slider31ValueChanging, true);
            app.Slider31.MinorTicks = [0 2.5 5 7.5 10 12.5 15 17.5 20 22.5 25 27.5 30 32.5 35 37.5 40 42.5 45 47.5 50 52.5 55 57.5 60 62.5 65 67.5 70 72.5 75 77.5 80 82.5 85 87.5 90 92.5 95 97.5 100];
            app.Slider31.Enable = 'off';
            app.Label31_6 = uilabel(app.GridLayout9);
            app.Label31_6.Enable = 'off';
            app.Label31_6.Text = 'TimeStamp (%):';
            app.Button_27 = uibutton(app.Grid31, 'push');
            app.Button_27.ButtonPushedFcn = createCallbackFcn(app, @Layout_Playback, true);
            app.Button_27.Icon = 'Media_Play.png';
            app.Button_27.Enable = 'off';
            app.Button_27.Text = '';
            app.Button_28 = uibutton(app.Grid31, 'push');
            app.Button_28.ButtonPushedFcn = createCallbackFcn(app, @Button_28Pushed, true);
            app.Button_28.Icon = 'reloadIcon.png';
            app.Button_28.Enable = 'off';
            app.Button_28.Text = '';
            % Show the figure after all components are created
            app.MainApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainApp)
        end
    end
end