classdef winFiscaliza < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FiscalizaUIFigure               matlab.ui.Figure
        Grid3_Report                    matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        Report_occType_2                matlab.ui.control.DropDown
        PrioridadeLabel                 matlab.ui.control.Label
        Report_DatePicker1              matlab.ui.control.DatePicker
        Report_DatePicker1_2            matlab.ui.control.DatePicker
        PrevisodeincioLabel             matlab.ui.control.Label
        DatalimiteLabel                 matlab.ui.control.Label
        SituaoLabel                     matlab.ui.control.Label
        Report_occType                  matlab.ui.control.DropDown
        INSP_GR08_2021_0017Label        matlab.ui.control.Label
        AspectosdegestodafiscalizaoLabel  matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        ClasseLabel                     matlab.ui.control.Label
        Report_occType_3                matlab.ui.control.DropDown
        Report_occType_4                matlab.ui.control.DropDown
        TipoLabel                       matlab.ui.control.Label
        AnodeexecuoEditFieldLabel       matlab.ui.control.Label
        AnodeexecuoEditField            matlab.ui.control.EditField
        ProcessoSEIEditFieldLabel       matlab.ui.control.Label
        ProcessoSEIEditField            matlab.ui.control.EditField
        DescrioTextAreaLabel            matlab.ui.control.Label
        DescrioTextArea                 matlab.ui.control.TextArea
        FiscaisenvolvidosnafiscalizaoLabel  matlab.ui.control.Label
        Tree3_2                         matlab.ui.container.Tree
        Fiscal1Node                     matlab.ui.container.TreeNode
        Fiscal2Node                     matlab.ui.container.TreeNode
        Fiscal3Node                     matlab.ui.container.TreeNode
        FiscalresponsvelDropDownLabel   matlab.ui.control.Label
        FiscalresponsvelDropDown        matlab.ui.control.DropDown
        EstimativasdehorasLabel         matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        PreparaoEditField               matlab.ui.control.NumericEditField
        PreparaoEditFieldLabel          matlab.ui.control.Label
        DeslocamentoEditField           matlab.ui.control.NumericEditField
        DeslocamentoEditFieldLabel      matlab.ui.control.Label
        ExecuoEditFieldLabel            matlab.ui.control.Label
        ExecuoEditField                 matlab.ui.control.NumericEditField
        ConclusoEditField               matlab.ui.control.NumericEditField
        ConclusoEditFieldLabel          matlab.ui.control.Label
        AspectostcnicosdafiscalizaoLabel  matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        RelatriodemonitoraoTextArea     matlab.ui.control.TextArea
        RelatriodemonitoraoTextAreaLabel  matlab.ui.control.Label
        LatitudeEditField               matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel          matlab.ui.control.Label
        LongitudeEditField              matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel         matlab.ui.control.Label
        InferiorEditField               matlab.ui.control.NumericEditField
        InferiorEditFieldLabel          matlab.ui.control.Label
        SuperiorEditField               matlab.ui.control.NumericEditField
        SuperiorEditFieldLabel          matlab.ui.control.Label
        EmissesidentificadasEditFieldLabel  matlab.ui.control.Label
        EmissesidentificadasEditField   matlab.ui.control.NumericEditField
        EmissesnolicenciadasEditField   matlab.ui.control.NumericEditField
        EmissesnolicenciadasEditFieldLabel  matlab.ui.control.Label
        ServiosdetelecomunicaesrelacionadossemissesidentificadasLabel  matlab.ui.control.Label
        ServiosdetelecomunicaesrelacionadossemissesTextArea  matlab.ui.control.TextArea
        MunicpioEditFieldLabel          matlab.ui.control.Label
        MunicpioEditField               matlab.ui.control.EditField
        LimitesdasfaixassobanliseLabel  matlab.ui.control.Label
        MHzLabel                        matlab.ui.control.Label
        MHzLabel_2                      matlab.ui.control.Label
        NotatextualainserirnohistricoTextArea  matlab.ui.control.TextArea
        NotatextualainserirnohistricoTextAreaLabel  matlab.ui.control.Label
        AtualizadoporFulanodeTalnodia12022021Label  matlab.ui.control.Label
        GridLayout5                     matlab.ui.container.GridLayout
        InspeonLabel                    matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Report_playButton               matlab.ui.control.Button
        Button                          matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function Fcn_status(app)
            
            switch status_id
                case 'Rascunho'
                    app.Report_occType.Items = {'Rascunho',            ...
                                                'Aguardando Execução', ...
                                                'Cancelada'};
                case 'Aguardando Execução'
                    app.Report_occType.Items = {'Aguardando Execução', ...
                                                'Em andamento',        ...
                                                'Cancelada'};
                case 'Em andamento'
                    app.Report_occType.Items = {'Aguardando Execução', ...
                                                'Em andamento',        ...
                                                'Relatando',           ...
                                                'Relatada',            ...
                                                'Cancelada'};
                case 'Relatando'
                    app.Report_occType.Items = {'Em andamento',        ...
                                                'Relatando',           ...
                                                'Relatada',            ...
                                                'Cancelada'};
                case 'Relatada'
                    app.Report_occType.Items = {'Em andamento',        ...
                                                'Relatada',            ...
                                                'Conferida'};
                case 'Conferida'
                    app.Report_occType.Items = {'Conferida'};
                case 'Cancelada'
                    app.Report_occType.Items = {'Cancelada'};
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
%             d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on');
%             
%             ISSUE = int64(app.Report_Issue.Value);
%             try
%                 if app.FiscalizaCount.Value == 0
%                     d.Message = sprintf('Inspeção nº %d\n\nPasso %d de 4.\nCriação do recurso de comunicação...', ISSUE, (app.FiscalizaCount.Value+1));
%                     app.mod = py.importlib.import_module('pyRedmine');
%                     
%                     d.Message = sprintf('Inspeção nº %d\n\nPasso %d de 4.\nValidação do login e senha...', ISSUE, (app.FiscalizaCount.Value+1));
%                     app.redmineObj = app.mod.connFcn('eric', '123456');
%                     d.Message = sprintf('Inspeção nº %d\n\nPasso %d de 4.\nEm edição campos do formulário para alterar o estado de "Rascunho" para "Aguardando execução".', ISSUE, (app.FiscalizaCount.Value+1));
%                     app.mod.setFcn(app.redmineObj, ISSUE, 1, py.list({'11', 'Primeira escrita...'}),                      ...
%                                  py.list({'{"valor":"Técnica","texto":"Técnica"}',                                    ...
%                                  '{"valor":"Uso do Espectro - Monitoração","texto":"Uso do Espectro - Monitoração"}', ...
%                                  'Uma descrição qualquer...', '174', py.list({'174', '302', '1207'})}));
%                 
%                 elseif app.FiscalizaCount.Value == 1
%                     d.Message = sprintf('Inspeção nº %d\n\nPasso %d de 4.\nEm edição campos do formulário para alterar o estado de "Aguardando execução" para "Em andamento".', ISSUE, (app.FiscalizaCount.Value+1));
%                     app.mod.setFcn(app.redmineObj, ISSUE, 2, py.list({'13', 'Segunda escrita....'}), ...
%                                  'C:/Users/Administrador/OneDrive - ANATEL/appAnalise/Temp/Report_2021.03.08_T19.52.18_FinalVersion.html');
%                     
%                 elseif app.FiscalizaCount.Value == 2
%                     d.Message = sprintf('Inspeção nº %d\n\nPasso %d de 4.\nEm edição campos do formulário para alterar o estado de "Em andamento" para "Relatando".', ISSUE, (app.FiscalizaCount.Value+1));
%                     app.mod.setFcn(app.redmineObj, ISSUE, 3, py.list({'14', 'Terceira escrita...', '2021-01-01', '2021-03-08'}), py.list({'108', 'MHz', '137', 'MHz'}));
%     
%                 elseif app.FiscalizaCount.Value == 3
%                     d.Message = sprintf('Inspeção nº %d\n\nPasso %d de 4.\nEm edição campos do formulário para alterar o estado de "Relatando" para "Relatada".', ISSUE, (app.FiscalizaCount.Value+1));
%                     app.mod.setFcn(app.redmineObj, ISSUE, 4, py.list({'15', 'Quarta escrita.....'}),                            ...
%                                  py.list({'{"valor":"BA/Salvador","texto":"BA/Salvador"}',                                      ...
%                                  '{"valor":"507 - RESTRITO - MÓVEL AERONÁUTICO","texto":"507 - RESTRITO - MÓVEL AERONÁUTICO"}', ...
%                                  '10', '2', '0', '1', '2', '3', '-12.123456', '-38.123456', 'Não se aplica PF - demanda específica', 'Não', '1'}));
%                     
%                 end
%                 
%                 
%                 if app.FiscalizaCount.Value == 3
%                     app.FiscalizaCount.Value = 0;
%                 else
%                     app.FiscalizaCount.Value = app.FiscalizaCount.Value + 1;
%                 end
%                 
%             catch ME
%                 uialert(app.MainApp, sprintf('ME.identifier: %s\nME.message: %s.', ME.identifier, ME.message), 'Alerta!', 'Icon', 'error')
%                 
%             end
            
        end

        % Value changed function: Report_occType_3
        function Report_occType_3ValueChanged(app, event)
            
            switch app.Report_occType_3.Value
                case 'Tributária'
                    app.Report_occType_4.Items = {'FUST - INTERCONEXÃO',    ...
                                                  'Situação Cadastral',     ...
                                                  'FUNTELL - INTERCONEXÃO', ...
                                                  'FUNTELL 0500',           ...
                                                  'FUST',                   ...
                                                  'FUNTELL'};
                    
                case 'Técnica'
                    app.Report_occType_4.Items = {'Outorga - Aspectos não Técnicos', ...
                                                  'Medição de CEMRF (RNI)',          ...
                                                  'Outorga - Aspectos Técnicos',     ...
                                                  'TV Digital',                      ...
                                                  'Uso do Espectro - Monitoração',   ...
                                                  'Uso do Espectro - Não Outorgado', ...
                                                  'Certificação'};
                    
                case 'Serviço'
                    app.Report_occType_4.Items = {'Serviço', ...
                                                  'Backhaul - PPP'};
                    
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FiscalizaUIFigure = uifigure('Visible', 'off');
            app.FiscalizaUIFigure.Name = 'Fiscaliza';
            app.FiscalizaUIFigure.Icon = 'Redmine.png';
            app.Grid3_Report = uigridlayout(app.FiscalizaUIFigure);
            app.Panel = uipanel(app.Grid3_Report);
            app.GridLayout = uigridlayout(app.Panel);
            app.Report_occType_2 = uidropdown(app.GridLayout);
            app.Report_occType_2.Items = {'Baixa', 'Normal', 'Alta', 'Urgente', 'Imediata'};
            app.Report_occType_2.Value = 'Baixa';
            app.PrioridadeLabel = uilabel(app.GridLayout);
            app.PrioridadeLabel.Text = 'Prioridade:';
            app.Report_DatePicker1 = uidatepicker(app.GridLayout);
            app.Report_DatePicker1.DisplayFormat = 'dd/MMM/uuuu';
            app.Report_DatePicker1_2 = uidatepicker(app.GridLayout);
            app.Report_DatePicker1_2.DisplayFormat = 'dd/MMM/uuuu';
            app.PrevisodeincioLabel = uilabel(app.GridLayout);
            app.PrevisodeincioLabel.Text = 'Previsão de início:';
            app.DatalimiteLabel = uilabel(app.GridLayout);
            app.DatalimiteLabel.Text = 'Data limite:';
            app.SituaoLabel = uilabel(app.GridLayout);
            app.SituaoLabel.Text = 'Situação:';
            app.Report_occType = uidropdown(app.GridLayout);
            app.Report_occType.Items = {'Rascunho', 'Aguardando Execução', 'Cancelada'};
            app.Report_occType.Value = 'Aguardando Execução';
            app.INSP_GR08_2021_0017Label = uilabel(app.Grid3_Report);
            app.INSP_GR08_2021_0017Label.Text = 'INSP_GR08_2021_0017';
            app.AspectosdegestodafiscalizaoLabel = uilabel(app.Grid3_Report);
            app.AspectosdegestodafiscalizaoLabel.Text = 'Aspectos de gestão da fiscalização.';
            app.Panel_2 = uipanel(app.Grid3_Report);
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.ClasseLabel = uilabel(app.GridLayout2);
            app.ClasseLabel.Text = 'Classe:';
            app.Report_occType_3 = uidropdown(app.GridLayout2);
            app.Report_occType_3.Items = {'Tributária', 'Técnica', 'Serviço'};
            app.Report_occType_3.ValueChangedFcn = createCallbackFcn(app, @Report_occType_3ValueChanged, true);
            app.Report_occType_3.Value = 'Técnica';
            app.Report_occType_4 = uidropdown(app.GridLayout2);
            app.Report_occType_4.Items = {'Uso do Espectro - Monitoração'};
            app.Report_occType_4.Value = 'Uso do Espectro - Monitoração';
            app.TipoLabel = uilabel(app.GridLayout2);
            app.TipoLabel.Text = 'Tipo:';
            app.AnodeexecuoEditFieldLabel = uilabel(app.GridLayout2);
            app.AnodeexecuoEditFieldLabel.Text = 'Ano de execução:';
            app.AnodeexecuoEditField = uieditfield(app.GridLayout2, 'text');
            app.AnodeexecuoEditField.Editable = 'off';
            app.AnodeexecuoEditField.Value = '2021';
            app.ProcessoSEIEditFieldLabel = uilabel(app.GridLayout2);
            app.ProcessoSEIEditFieldLabel.Text = 'Processo (SEI):';
            app.ProcessoSEIEditField = uieditfield(app.GridLayout2, 'text');
            app.ProcessoSEIEditField.Editable = 'off';
            app.ProcessoSEIEditField.Value = '53554.001111/2020-12';
            app.DescrioTextAreaLabel = uilabel(app.GridLayout2);
            app.DescrioTextAreaLabel.Text = 'Descrição:';
            app.DescrioTextArea = uitextarea(app.GridLayout2);
            app.DescrioTextArea.Value = {'Um texto qualquer...'};
            app.FiscaisenvolvidosnafiscalizaoLabel = uilabel(app.GridLayout2);
            app.FiscaisenvolvidosnafiscalizaoLabel.Text = 'Fiscais envolvidos na fiscalização:';
            app.Tree3_2 = uitree(app.GridLayout2);
            app.Tree3_2.Multiselect = 'on';
            app.Fiscal1Node = uitreenode(app.Tree3_2);
            app.Fiscal1Node.Text = 'Fiscal 1';
            app.Fiscal2Node = uitreenode(app.Tree3_2);
            app.Fiscal2Node.Text = 'Fiscal 2';
            app.Fiscal3Node = uitreenode(app.Tree3_2);
            app.Fiscal3Node.Text = 'Fiscal 3';
            app.FiscalresponsvelDropDownLabel = uilabel(app.GridLayout2);
            app.FiscalresponsvelDropDownLabel.Text = 'Fiscal responsável:';
            app.FiscalresponsvelDropDown = uidropdown(app.GridLayout2);
            app.FiscalresponsvelDropDown.Items = {};
            app.FiscalresponsvelDropDown.Enable = 'off';
            app.FiscalresponsvelDropDown.Value = {};
            app.EstimativasdehorasLabel = uilabel(app.GridLayout2);
            app.EstimativasdehorasLabel.Text = 'Estimativas de horas:';
            app.Panel_3 = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.Panel_3);
            app.PreparaoEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PreparaoEditFieldLabel = uilabel(app.GridLayout3);
            app.PreparaoEditFieldLabel.Text = 'Preparação:';
            app.DeslocamentoEditField = uieditfield(app.GridLayout3, 'numeric');
            app.DeslocamentoEditFieldLabel = uilabel(app.GridLayout3);
            app.DeslocamentoEditFieldLabel.Text = 'Deslocamento:';
            app.ExecuoEditFieldLabel = uilabel(app.GridLayout3);
            app.ExecuoEditFieldLabel.Text = 'Execução:';
            app.ExecuoEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ConclusoEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ConclusoEditFieldLabel = uilabel(app.GridLayout3);
            app.ConclusoEditFieldLabel.Text = 'Conclusão:';
            app.AspectostcnicosdafiscalizaoLabel = uilabel(app.Grid3_Report);
            app.AspectostcnicosdafiscalizaoLabel.Text = 'Aspectos técnicos da fiscalização.';
            app.Panel_4 = uipanel(app.Grid3_Report);
            app.GridLayout4 = uigridlayout(app.Panel_4);
            app.RelatriodemonitoraoTextArea = uitextarea(app.GridLayout4);
            app.RelatriodemonitoraoTextArea.Value = {'nomedoarquivo.html'};
            app.RelatriodemonitoraoTextAreaLabel = uilabel(app.GridLayout4);
            app.RelatriodemonitoraoTextAreaLabel.Text = 'Relatório de monitoração:';
            app.LatitudeEditField = uieditfield(app.GridLayout4, 'numeric');
            app.LatitudeEditField.Editable = 'off';
            app.LatitudeEditFieldLabel = uilabel(app.GridLayout4);
            app.LatitudeEditFieldLabel.Text = 'Latitude:';
            app.LongitudeEditField = uieditfield(app.GridLayout4, 'numeric');
            app.LongitudeEditField.Editable = 'off';
            app.LongitudeEditFieldLabel = uilabel(app.GridLayout4);
            app.LongitudeEditFieldLabel.Text = 'Longitude:';
            app.InferiorEditField = uieditfield(app.GridLayout4, 'numeric');
            app.InferiorEditField.Editable = 'off';
            app.InferiorEditField.Value = 54;
            app.InferiorEditFieldLabel = uilabel(app.GridLayout4);
            app.InferiorEditFieldLabel.Text = 'Inferior:';
            app.SuperiorEditField = uieditfield(app.GridLayout4, 'numeric');
            app.SuperiorEditField.Editable = 'off';
            app.SuperiorEditField.Value = 798;
            app.SuperiorEditFieldLabel = uilabel(app.GridLayout4);
            app.SuperiorEditFieldLabel.Text = 'Superior:';
            app.EmissesidentificadasEditFieldLabel = uilabel(app.GridLayout4);
            app.EmissesidentificadasEditFieldLabel.Text = 'Emissões identificadas:';
            app.EmissesidentificadasEditField = uieditfield(app.GridLayout4, 'numeric');
            app.EmissesidentificadasEditField.Editable = 'off';
            app.EmissesidentificadasEditField.Value = 64;
            app.EmissesnolicenciadasEditField = uieditfield(app.GridLayout4, 'numeric');
            app.EmissesnolicenciadasEditField.Editable = 'off';
            app.EmissesnolicenciadasEditField.Value = 8;
            app.EmissesnolicenciadasEditFieldLabel = uilabel(app.GridLayout4);
            app.EmissesnolicenciadasEditFieldLabel.Text = 'Emissões não licenciadas:';
            app.ServiosdetelecomunicaesrelacionadossemissesidentificadasLabel = uilabel(app.GridLayout4);
            app.ServiosdetelecomunicaesrelacionadossemissesidentificadasLabel.Text = 'Serviços de telecomunicações relacionados às emissões identificadas:';
            app.ServiosdetelecomunicaesrelacionadossemissesTextArea = uitextarea(app.GridLayout4);
            app.ServiosdetelecomunicaesrelacionadossemissesTextArea.Value = {'019 - Limitado Privado'; '700 - Radiodifusão Sonora em FM'};
            app.MunicpioEditFieldLabel = uilabel(app.GridLayout4);
            app.MunicpioEditFieldLabel.Text = 'Município:';
            app.MunicpioEditField = uieditfield(app.GridLayout4, 'text');
            app.MunicpioEditField.Value = 'Salvador/BA';
            app.LimitesdasfaixassobanliseLabel = uilabel(app.GridLayout4);
            app.LimitesdasfaixassobanliseLabel.Text = 'Limites das faixas sob análise:';
            app.MHzLabel = uilabel(app.GridLayout4);
            app.MHzLabel.Text = 'MHz';
            app.MHzLabel_2 = uilabel(app.GridLayout4);
            app.MHzLabel_2.Text = 'MHz';
            app.NotatextualainserirnohistricoTextArea = uitextarea(app.GridLayout4);
            app.NotatextualainserirnohistricoTextArea.Value = {'54, 88, 4, 1'; '72, 108, 26, 4'; '174, 216, 14, 2'; '470, 798, 20, 1'};
            app.NotatextualainserirnohistricoTextAreaLabel = uilabel(app.GridLayout4);
            app.NotatextualainserirnohistricoTextAreaLabel.Text = 'Nota textual a inserir no histórico:';
            app.AtualizadoporFulanodeTalnodia12022021Label = uilabel(app.Grid3_Report);
            app.AtualizadoporFulanodeTalnodia12022021Label.Text = 'Atualizado por Fulano de Tal no dia 12/02/2021.';
            app.GridLayout5 = uigridlayout(app.Grid3_Report);
            app.InspeonLabel = uilabel(app.GridLayout5);
            app.InspeonLabel.Text = 'Inspeção nº';
            app.Label = uilabel(app.GridLayout5);
            app.Label.Text = '12345';
            app.Report_playButton = uibutton(app.Grid3_Report, 'push');
            app.Report_playButton.Icon = 'Edit2.png';
            app.Report_playButton.Text = 'Editar';
            app.Button = uibutton(app.Grid3_Report, 'push');
            app.Button.Icon = 'reloadIcon.png';
            app.Button.Text = '';
            % Show the figure after all components are created
            app.FiscalizaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winFiscaliza

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FiscalizaUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FiscalizaUIFigure)
        end
    end
end